classdef AnalyseExe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
        LIGGGHTSAnalysisButtonGroup   matlab.ui.container.ButtonGroup
        AnisotropyButton              matlab.ui.control.ToggleButton
        BasicInfoButton               matlab.ui.control.ToggleButton
        DisplacementButton            matlab.ui.control.ToggleButton
        EdgesRatioButton              matlab.ui.control.ToggleButton
        ExeAllButton                  matlab.ui.control.ToggleButton
        ExtStressStrainButton         matlab.ui.control.ToggleButton
        ForceChainButton              matlab.ui.control.ToggleButton
        LoopsClustersButton           matlab.ui.control.ToggleButton
        SettingsButton                matlab.ui.control.ToggleButton
        StrainTensorButton            matlab.ui.control.ToggleButton
        StressTensorButton            matlab.ui.control.ToggleButton
        VoidRatioButton               matlab.ui.control.ToggleButton
        StrainLoadOptionsPanel        matlab.ui.container.Panel
        StnLoadAveClusterW2Button     matlab.ui.control.Button
        StnLoadStnClButton            matlab.ui.control.Button
        StnLoadBaseButton             matlab.ui.control.Button
        ExtraClusterOptionsPanel      matlab.ui.container.Panel
        LPACLabel                     matlab.ui.control.Label
        LPACSwitch                    matlab.ui.control.Switch
        LPStnSwitchLabel              matlab.ui.control.Label
        LPStnSwitch                   matlab.ui.control.Switch
        LPCTrSwitchLabel              matlab.ui.control.Label
        LPCTrSwitch                   matlab.ui.control.Switch
        LPDefSwitch                   matlab.ui.control.Switch
        LPDefSwitchLabel              matlab.ui.control.Label
        LPAniSwitchLabel              matlab.ui.control.Label
        LPAniSwitch                   matlab.ui.control.Switch
        LPVRSwitchLabel               matlab.ui.control.Label
        LPVRSwitch                    matlab.ui.control.Switch
        LPStrSwitch                   matlab.ui.control.Switch
        StressLabel                   matlab.ui.control.Label
        LoopsLoadOptionsPanel         matlab.ui.container.Panel
        LoadLoopTrfButton             matlab.ui.control.Button
        LoadLoopStrButton             matlab.ui.control.Button
        LoadLoopAniButton             matlab.ui.control.Button
        LoadLoopDefButton             matlab.ui.control.Button
        LoadLoopVRButton              matlab.ui.control.Button
        LoadLoopBLButton              matlab.ui.control.Button
        TestingPanel                  matlab.ui.container.Panel
        TesterButton                  matlab.ui.control.Button
        NewRectangleSizePanel         matlab.ui.container.Panel
        NRAngleEF                     matlab.ui.control.NumericEditField
        AngleLabel                    matlab.ui.control.Label
        NRHeightZEF                   matlab.ui.control.NumericEditField
        HeightzEditFieldLabel         matlab.ui.control.Label
        NRLengthYEF                   matlab.ui.control.NumericEditField
        LengthyLabel_2                matlab.ui.control.Label
        NRWidthXEF                    matlab.ui.control.NumericEditField
        WidgthxEditFieldLabel         matlab.ui.control.Label
        NROriginZEF                   matlab.ui.control.NumericEditField
        OriginzEditFieldLabel         matlab.ui.control.Label
        NROriginYEF                   matlab.ui.control.NumericEditField
        OriginyEditFieldLabel         matlab.ui.control.Label
        NRCancelButton                matlab.ui.control.Button
        NRCreateButton                matlab.ui.control.Button
        NewRectangleOptionsPanel      matlab.ui.container.Panel
        NRCancelButton2               matlab.ui.control.Button
        NRTriaxialButton              matlab.ui.control.Button
        NREnterValuesButton           matlab.ui.control.Button
        NRChooseinDrawingButton       matlab.ui.control.Button
        WarningPanel                  matlab.ui.container.Panel
        CouldntfindthenecessaryfilesforDrawingLabel  matlab.ui.control.Label
        CalculatingPanel              matlab.ui.container.Panel
        TimeRem                       matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        StepTracker                   matlab.ui.control.Label
        UserPanel                     matlab.ui.container.Panel
        ConsoStepButton               matlab.ui.control.Button
        CalcInt                       matlab.ui.control.NumericEditField
        CalculationIntervalEditFieldLabel  matlab.ui.control.Label
        N2EF                          matlab.ui.control.NumericEditField
        N1EF                          matlab.ui.control.NumericEditField
        EndStepSlider                 matlab.ui.control.Slider
        EndStepSliderLabel            matlab.ui.control.Label
        StartStepSlider               matlab.ui.control.Slider
        StartStepSliderLabel          matlab.ui.control.Label
        GrainsButton                  matlab.ui.control.StateButton
        RotationEF                    matlab.ui.control.NumericEditField
        RotationLabel                 matlab.ui.control.Label
        ColEditField                  matlab.ui.control.NumericEditField
        ColEditFieldLabel             matlab.ui.control.Label
        LinesEditField                matlab.ui.control.NumericEditField
        LinesEditFieldLabel           matlab.ui.control.Label
        SubdivisionButton             matlab.ui.control.StateButton
        NewRectButton                 matlab.ui.control.Button
        LoadSurfButton                matlab.ui.control.Button
        PartialSwitch                 matlab.ui.control.Switch
        ResetZoomButton               matlab.ui.control.Button
        AnisotropyPanel               matlab.ui.container.Panel
        LoadAni                       matlab.ui.control.Button
        AniDescription                matlab.ui.control.Label
        AniLabel                      matlab.ui.control.Label
        CalculateAni                  matlab.ui.control.Button
        BasicInfoPanel                matlab.ui.container.Panel
        LoadBI                        matlab.ui.control.Button
        BIDescription                 matlab.ui.control.Label
        BILabel                       matlab.ui.control.Label
        CalculateBI                   matlab.ui.control.Button
        DisplacementPanel             matlab.ui.container.Panel
        NeighborValuesEditField       matlab.ui.control.EditField
        NeighborValuesEditFieldLabel  matlab.ui.control.Label
        DplBasestepLabel              matlab.ui.control.Label
        DplStep0EF                    matlab.ui.control.NumericEditField
        DPLValueLabel                 matlab.ui.control.Label
        DplDescription                matlab.ui.control.Label
        DplStep0Switch                matlab.ui.control.Switch
        DplLabel                      matlab.ui.control.Label
        CalculateDpl                  matlab.ui.control.Button
        EdgesRatioPanel               matlab.ui.container.Panel
        LoadEdgR                      matlab.ui.control.Button
        EdgRDescription               matlab.ui.control.Label
        EdgRLabel                     matlab.ui.control.Label
        CalculateEdgR                 matlab.ui.control.Button
        ExeAllPanel                   matlab.ui.container.Panel
        StrT_EA_DropDown              matlab.ui.control.DropDown
        SOut_EA_DropDown              matlab.ui.control.DropDown
        BI_EA_CB                      matlab.ui.control.CheckBox
        SOut_EA_CB                    matlab.ui.control.CheckBox
        StrT_EA_CB                    matlab.ui.control.CheckBox
        SInt_EA_CB                    matlab.ui.control.CheckBox
        Ani_EA_CB                     matlab.ui.control.CheckBox
        VR_EA_CB                      matlab.ui.control.CheckBox
        Loop_EA_CB                    matlab.ui.control.CheckBox
        EALabel                       matlab.ui.control.Label
        CalculateEA                   matlab.ui.control.Button
        ExternalForcesPanel           matlab.ui.container.Panel
        LoadExtF                      matlab.ui.control.Button
        ExtFDescription               matlab.ui.control.Label
        ExtFLabel                     matlab.ui.control.Label
        CalculateExtF                 matlab.ui.control.Button
        ForceChainsPanel              matlab.ui.container.Panel
        FCBendCLTCBox                 matlab.ui.control.CheckBox
        FCBendAngleEF                 matlab.ui.control.NumericEditField
        FCBuckAngleEFLabel            matlab.ui.control.Label
        FCBendCBox                    matlab.ui.control.CheckBox
        FCGrVtkEF                     matlab.ui.control.EditField
        FCGrVtkCBox                   matlab.ui.control.CheckBox
        FCClusterCBox                 matlab.ui.control.CheckBox
        FCBaseCBox                    matlab.ui.control.CheckBox
        FCForceCoefEF                 matlab.ui.control.NumericEditField
        ForceCoefLabel                matlab.ui.control.Label
        FCAngEF                       matlab.ui.control.NumericEditField
        AnglelimitEditFieldLabel      matlab.ui.control.Label
        FCDescription                 matlab.ui.control.Label
        LoadFC                        matlab.ui.control.Button
        FCLabel                       matlab.ui.control.Label
        CalculateFC                   matlab.ui.control.Button
        StressTensorPanel             matlab.ui.container.Panel
        StrTDescription               matlab.ui.control.Label
        LoadStrT                      matlab.ui.control.Button
        StrTLabel                     matlab.ui.control.Label
        CalculateStrT                 matlab.ui.control.Button
        VoidRatioPanel                matlab.ui.container.Panel
        VRDescription                 matlab.ui.control.Label
        LoadVR                        matlab.ui.control.Button
        VRLabel                       matlab.ui.control.Label
        CalculateVR                   matlab.ui.control.Button
        LoopsPanel                    matlab.ui.container.Panel
        LPLoadMenuButton              matlab.ui.control.StateButton
        ExtraOptionsButton            matlab.ui.control.StateButton
        LPDescription                 matlab.ui.control.Label
        LPVtkSwitch                   matlab.ui.control.Switch
        VtkFilesLabel                 matlab.ui.control.Label
        LPLabel                       matlab.ui.control.Label
        CalculateLP                   matlab.ui.control.Button
        StrainTensorPanel             matlab.ui.container.Panel
        StnVTKCB                      matlab.ui.control.CheckBox
        StnLoadMenuButton             matlab.ui.control.StateButton
        StnClusterCB                  matlab.ui.control.CheckBox
        StnDropDown                   matlab.ui.control.DropDown
        CalculationtypeDropDownLabel  matlab.ui.control.Label
        StnDescription                matlab.ui.control.Label
        StnLabel                      matlab.ui.control.Label
        CalculateStn                  matlab.ui.control.Button
        SettingsPanel                 matlab.ui.container.Panel
        FontSizeEF                    matlab.ui.control.NumericEditField
        LinewidthLabel_2              matlab.ui.control.Label
        PlotWidthEF                   matlab.ui.control.NumericEditField
        LinewidthLabel                matlab.ui.control.Label
        MarkSwitch                    matlab.ui.control.Switch
        MarkinitpeakendSwitchLabel    matlab.ui.control.Label
        LegendsCB                     matlab.ui.control.CheckBox
        PlotoptionsLabel              matlab.ui.control.Label
        TitlesCB                      matlab.ui.control.CheckBox
        exportSizeEF                  matlab.ui.control.EditField
        PlotssizeLabel                matlab.ui.control.Label
        CourbePointsSwitch            matlab.ui.control.Switch
        CourbePointsSwitchLabel       matlab.ui.control.Label
        boxZEF                        matlab.ui.control.NumericEditField
        boxYEF                        matlab.ui.control.NumericEditField
        boxXEF                        matlab.ui.control.NumericEditField
        IntervalEF                    matlab.ui.control.NumericEditField
        LastStepEF                    matlab.ui.control.NumericEditField
        FirstStepEF                   matlab.ui.control.NumericEditField
        IntervalLabel                 matlab.ui.control.Label
        LastStepLabel                 matlab.ui.control.Label
        FirstStepLabel                matlab.ui.control.Label
        VTKpathEF                     matlab.ui.control.EditField
        PathtoVTKfilesLabel           matlab.ui.control.Label
        GVpathEF                      matlab.ui.control.EditField
        PathtogeneralvariablesLabel   matlab.ui.control.Label
        WidthxLabel                   matlab.ui.control.Label
        LengthyLabel                  matlab.ui.control.Label
        HeightzLabel                  matlab.ui.control.Label
        GeometricaldatamLabel         matlab.ui.control.Label
        LoadSimButton                 matlab.ui.control.Button
        FilesdataLabel                matlab.ui.control.Label
        FormatEF                      matlab.ui.control.EditField
        FilesextensionLabel           matlab.ui.control.Label
        PistEF                        matlab.ui.control.EditField
        RadicalcotainingpistonsdataLabel  matlab.ui.control.Label
        GrainsForceEF                 matlab.ui.control.EditField
        RadicalcotaininggrainseffortsdataLabel  matlab.ui.control.Label
        GrainsEF                      matlab.ui.control.EditField
        RadicalcotaininggrainsdataLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        % Objects
        PartialData
        TrialData
        % Handles of the drawings present on the graph
        PlotRect
        PlotSubdv
        PlotGrains
        PlotGrainsTot
        PlotArea
        % Booleans
        BoolRecalculateGrains   %Drawing grains calculation. if 0 calculate, if 1 dont.
        Bool3D                  %check 3D or 2D. 3D =1 , 2D=0 - calculated on trialData class
        checkPiston             %check if x piston exist - calculated on Grain class
        %Important constants
        NbGrains                %nb of grains
        TimeStep                %correlation between calculation timestep and real seconds
        ConsoStep               %step ending consolidaiton/begining the compression
        Figsize                 %usual figure size
        SimType                 %simulation type- 1 drained, 2 isochoric, 3 q-cst
        ConsoDpl                %displacement of pistons in consolidation part
        ExeType                 %1 for conso+comp, 2 for comp only
        QcstStep                %Qcst changing steps
        PlotColors              %Array of matlab usual plot colors
        TimeTracker             %Tracks estimated remaining time
        DEMSoftware             %1 LIGGGHTS, 2 YADE - for how to load servofiles
        %Path
        SavePath                %Path to log.liggghts file
        VtkFolder               %Path to folder containing vtk data
        DataFolder              %Path to folder containing grains/force data
        
    end
    methods (Access = public)
        function LogReader(app,path)
            %LOGREADER read log file and save important variables
            %   This function will read the 'log.liggghts' file that is
            %   created after the simulation. The data that interest us is
            %   situated at the beggining at the file. The data will be
            %   identified and saved on the correct position.
            
            %Try to find LIGGGHTS log in the usual place, if it does not
            %ask the user for the placement

            %Ask for file
            if nargin>1
                fName="LiggghtstoMatlab.txt";
                fPath=path;
            else
                [fName,fPath,~] = uigetfile( {'*.txt;*.liggghts',...
                'Text files(*.txt,*.liggghts)';'*.*',...
                'All Files (*.*)'},'Select file for loading');
                if fName==0;return;end
            end
            [app,stp1,stp12,stp2,intV]=logReader(app,fPath,fName);
            %Step information
            stepSlider(app,stp1,stp12,stp2,intV)
        end
        function stepSlider(app,stp1,stp12,stp2,intV)
            %Step information - Values on 'settings'pannel for informatio
            %checking
            app.FirstStepEF.Value=stp1;
            app.LastStepEF.Value=stp2;
            %Add values to the slider and define its limits - values on EF
            %pannel for calculations
            app.N1EF.Limits=[stp1,stp2];
            app.N1EF.Value=stp1;
            app.N2EF.Limits=[stp1,stp2];
            app.N2EF.Value=stp2;
            
            %Marks the consolidation Step
            app.ConsoStep=stp12;
            app.DplStep0EF.Value=stp12;
            app.StartStepSlider.MajorTicks=(stp12-stp1)/(stp2-stp1)*100;
            %Interval
            if nargin>4
                app.IntervalEF.Value=intV;
                app.CalcInt.Value=intV;
            end
        end
        function FirstValues(app)
            %FIRSTVALUES read pistons information, check the dimension
            %   This function will look for the file containing the
            %   information about the displacement of the pistons. It will
            %   then create a 'trialData' object and save it as a property.
            %   Also it will run the 'grains' class once to find out if the
            %   simulation was made into 2D or 3D. This calculation is made
            %   inside this class because we can analyse grains positions.
            
            %Create trialData object containg all the pistons data
            TD=trialData(app);
            if isempty(TD.Step)
                warndlg(['Files containing servo data could not be'...
                    'found. Check Settings values to make sure the app'...
                    'will work']);return;
            end
            app.checkPiston=TD.checkPiston;
            app.TrialData=TD;
            
            %Check first and last step - take into account the case where
            %consolidation data is missing
            if app.N1EF.Value~=TD.Step(1)
                stepSlider(app,TD.Step(1),app.ConsoStep,app.N2EF.Value)
            end
            %Check dimesions - check if 3D or 2D - diference between X
            %coordinates must be smaller then the max radius for 2D
            gr=grains('BASIC',app.LastStepEF.Value,'',app);
            if abs(max(gr.Coord(:,1))-min(gr.Coord(:,1)))>max(gr.Radius)
                app.Bool3D=1; %3D
            else
                app.Bool3D=0; %2D
            end
            %Calc infl pts
            app.TrialData=inflectionPoints(app.TrialData,app);
            app.NbGrains=gr.Nb;
            app.Figsize=str2double(regexp(app.exportSizeEF.Value,...
                '\d*','match'));
        end
        function PlotUpdater(app,varargin)
            %PLOTUPDATER update the apps drawing
            % This function will control the 'AppGraph' function. It will
            % tell it when to draw and what to draw. It will also control
            % the handle of the drawings, turning them visible or not
            % depending on conditions.
            
            %Check if values exist
            try tf=(app.N1EF.Value<app.TrialData.Step(1) ...
                    || app.N1EF.Value>app.TrialData.Step(end));
            catch
                tf=1;
            end
            if tf
                app.WarningPanel.set('Visible','On');return;
            else
                app.WarningPanel.set('Visible','Off');
            end
            
            %Start drawing
            [PA,PR,PS,PG,PGT]=AppGraph(app,varargin{:}); %update draw image
            app.PlotArea=PA;
            app.PlotRect=PR;    app.PlotSubdv=PS;
            app.PlotGrains=PG;  app.PlotGrainsTot=PGT;
            %Control Rectangle plot
            if ~isempty(app.PlotRect)
                if isequal(app.PartialSwitch.Value,'Partial')
                    app.PlotRect.set('Visible','on')
                else
                    app.PlotRect.set('Visible','off')
                end
            end
            %Control Subdivision plot
            if ~isempty(app.PlotSubdv)
                if app.SubdivisionButton.Value==1
                    app.PlotSubdv.set('Visible','on')
                else
                    app.PlotSubdv.set('Visible','off')
                end
            end
            %Control Grains plot
            if app.GrainsButton.Value==1
                if isequal(app.PartialSwitch.Value,'Partial')
                    of=["on","off"];
                else
                    of=["off","on"];
                end
            else
                of=["off","off"];
            end
            if ~isempty(app.PlotGrains);app.PlotGrains.set('Visible',of(1));end
            if ~isempty(app.PlotGrainsTot);app.PlotGrainsTot.set('Visible',of(2));end
        end
        function PartButEnabler(app)
            %PartButEnabler dis/enable buttons
            %   Disable or enable the 'partial calculation' buttons
            %   depending on the selection made by the user
            if isequal(app.PartialSwitch.Value,'Partial') && isempty(app.PartialData)
                of='off';
            else
                of='on';
            end
            app.CalculateStn.set('Enable',of);
            app.CalculateAni.set('Enable',of);
            app.CalculateLP.set('Enable',of);
            app.CalculateVR.set('Enable',of);
            app.CalculateStrT.set('Enable',of);
            app.CalculateEA.set('Enable',of);
        end
        function appLock(app,mode)
            %APPLOCK this function will lock the application buttons during
            %the calculations. -- dont work
            switch mode
                case 'LOCK'; of='off';
                case 'UNLOCK';of='on';
                    CalcPanel(app,'','','','off');
            end
            app.LeftPanel.set('Enable',of)
            app.LoopsLoadOptionsPanel.set('Enable',of)
            app.UserPanel.set('Enable',of)
            app.AnisotropyPanel.set('Enable',of)
            app.BasicInfoPanel.set('Enable',of)
            app.DisplacementPanel.set('Enable',of)
            app.EdgesRatioPanel.set('Enable',of)
            app.ExeAllPanel.set('Enable',of)
            app.ExternalForcesPanel.set('Enable',of)
            app.ForceChainsPanel.set('Enable',of)
            app.LoopsPanel.set('Enable',of)
            app.StressTensorPanel.set('Enable',of)
            app.StrainTensorPanel.set('Enable',of)
            app.VoidRatioPanel.set('Enable',of)
            app.SettingsPanel.set('Enable',of)
            app.ResetZoomButton.set('Enable',of)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            %STARTUPFCN functions to execute on startup
            %   When open the app, it will call these three functions. The
            %   first one obtain important data from the log of the
            %   simulation, the second one get piston's data, and the last
            %   one updates the app's graphics.

            %add functions to path
            addpath('src')

            %Begin app
            if nargin>1
                LogReader(app,path);
            else
                LogReader(app);
            end
            if isempty(app.SavePath)
                app.delete;
                warndlg('LIGGGHTS log was not found, please try again');
                return;
            end
            FirstValues(app);
            PlotUpdater(app,'BASE');
        end

        % Selection changed function: LIGGGHTSAnalysisButtonGroup
        function LIGGGHTSAnalysisButtonGroupSelectionChanged(app, event)
            %BUTTONGROUPSELECTION control pannel visibility
            %   This function will controll wich panels should be visible
            %   or not depending on wich button was chosen.
            selectedButton = app.LIGGGHTSAnalysisButtonGroup.SelectedObject;
            if isequal(app.ExternalForcesPanel.Visible,'on') ||...
                    selectedButton ==app.ExtStressStrainButton
                PartialSwitchValueChanged(app);
            end
            %Other elements
            
            %Pannels
            app.AnisotropyPanel.set('Visible','off');
            app.BasicInfoPanel.set('Visible','off');
            app.DisplacementPanel.set('Visible','off');
            app.EdgesRatioPanel.set('Visible','off');
            app.ExeAllPanel.set('Visible','off');
            app.ExternalForcesPanel.set('Visible','off');
            app.ForceChainsPanel.set('Visible','off');
            app.LoopsPanel.set('Visible','off');
            app.VoidRatioPanel.set('Visible','off');
            app.StressTensorPanel.set('Visible','off');
            app.StrainTensorPanel.set('Visible','off');
            app.SettingsPanel.set('Visible','off');
            app.UserPanel.set('Visible','on');
            %loops load update fix
            app.LoopsLoadOptionsPanel.set('Visible','off')
            app.ExtraClusterOptionsPanel.set('Visible','off')
            app.ExtraOptionsButton.set('Value',0)
            app.LPLoadMenuButton.set('Visible','off')
            app.StrainLoadOptionsPanel.set('Visible','off')
            app.StnLoadMenuButton.set('Visible','off')
            app.CalculateLP.set('Enable','on')
            switch selectedButton %Pannel Control
                case app.AnisotropyButton; app.AnisotropyPanel.set('Visible','on');
                case app.BasicInfoButton
                    app.UserPanel.set('Visible','off');
                    app.BasicInfoPanel.set('Visible','on');
                case app.DisplacementButton; app.DisplacementPanel.set('Visible','on');
                case app.EdgesRatioButton;app.EdgesRatioPanel.set('Visible','on');
                case app.ExeAllButton;app.ExeAllPanel.set('Visible','on');
                case app.ExtStressStrainButton;app.ExternalForcesPanel.set('Visible','on');
                case app.ForceChainButton; app.ForceChainsPanel.set('Visible','on');
                case app.LoopsClustersButton; app.LoopsPanel.set('Visible','on');
                case app.VoidRatioButton;app.VoidRatioPanel.set('Visible','on');
                case app.StressTensorButton; app.StressTensorPanel.set('Visible','on');
                case app.StrainTensorButton;app.StrainTensorPanel.set('Visible','on');
                case app.SettingsButton
                    app.UserPanel.set('Visible','off');
                    app.SettingsPanel.set('Visible','on');
            end
            PartialSwitchValueChanged(app) %update EditFields panel
        end

        % Button pushed function: CalculateBI
        function CalculateBIButtonPushed(app, event)
            exe_BasicInfo(app);
        end

        % Button pushed function: CalculateAni
        function CalculateAniButtonPushed(app, event)
            switch app.PartialSwitch.Value
                case 'Total';PD='';
                case 'Partial';PD=app.PartialData;
            end
            
            appLock(app,'LOCK');
            try exe_Anisotropy(PD,app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: LoadAni
        function LoadAniButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_Anisotropy('LOAD',app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Button pushed function: CalculateLP
        function CalculateLPButtonPushed(app, event)
            switch app.PartialSwitch.Value
                case 'Total';PD='';
                case 'Partial';PD=app.PartialData;
            end
            appLock(app,'LOCK');
            try exe_LoopsCounter(app,PD,app.LPVtkSwitch.Value)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Value changed function: LPLoadMenuButton
        function LoadLoopMenuButtonPushed(app, event)
            app.LoopsLoadOptionsPanel.Visible=app.LPLoadMenuButton.Value;
            app.CalculateLP.Enable=~app.LPLoadMenuButton.Value;
        end

        % Button pushed function: LoadLoopAniButton
        function LoadLoopAniButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'ANISOTROPY','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Button pushed function: LoadLoopBLButton
        function LoadLoopBLButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'NORMAL','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Button pushed function: LoadLoopDefButton
        function LoadLoopDefButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'DEFORMABILITY','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Button pushed function: LoadLoopVRButton
        function LoadLoopVRButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'VOIDRATIO','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Button pushed function: LoadLoopStrButton
        function LoadLoopStrButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'STRESS','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Button pushed function: LoadLoopTrfButton
        function LoadLoopTrfButtonPushed(app, event)
            if isequal(app.LoopsLoadOptionsPanel.Visible,'on')
                exe_LoopsCounter(app,'STRESS','Load')
                appLock(app,'LOCK');
                try exe_LoopsCounter(app,'CLTRF','Load')
                catch ME
                    appLock(app,'UNLOCK');
                    rethrow(ME);
                end
                appLock(app,'UNLOCK');
            end
        end

        % Value changed function: ExtraOptionsButton
        function ExtraOptionsButtonValueChanged(app, event)
            app.ExtraClusterOptionsPanel.Visible=...
                app.ExtraOptionsButton.Value;
        end

        % Button pushed function: CalculateVR
        function CalculateVRButtonPushed(app, event)
            switch app.PartialSwitch.Value
                case 'Total';PD='';
                case 'Partial';PD=app.PartialData;
            end
            appLock(app,'LOCK');
            try exe_VoidRatio(PD,app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: LoadVR
        function LoadVRButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_VoidRatio('LOAD',app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Button pushed function: CalculateStrT
        function CalculateStrTButtonPushed(app, event)
            switch app.PartialSwitch.Value
                case 'Total';PD='';
                case 'Partial';PD=app.PartialData;
            end
            appLock(app,'LOCK');
            try exe_StressTensor(PD,app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: LoadStrT
        function LoadStrTButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_StressTensor('LOAD',app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Button pushed function: CalculateExtF
        function CalculateExtFButtonPushed(app, event)
            exe_ExtForces('',app);
        end

        % Button pushed function: LoadExtF
        function LoadExtFButtonPushed(app, event)
            exe_ExtForces('LOAD',app);
        end

        % Button pushed function: CalculateDpl
        function CalculateDplButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_Displacement(app)
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: CalculateFC
        function CalculateFCButtonPushed(app, event)
            if isequal(app.PartialSwitch.Value,'Partial')
                warndlg(['Partial calculation does not work'...
                       ' for Forcechain']);
                return
            end
            appLock(app,'LOCK');
            try exe_ForceChains(app);
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: LoadFC
        function LoadFCButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_ForceChains(app,'LOAD');
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: CalculateStn
        function CalculateStnButtonPushed(app, event)
            switch app.PartialSwitch.Value
                case 'Total';PD='';
                case 'Partial';PD=app.PartialData;
            end
            appLock(app,'LOCK');
            try exe_StrainTensor(app,app.StnDropDown.Value,PD);
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
            CalcPanel(app,'','','','off');
        end

        % Button pushed function: StnLoadBaseButton
        function LoadStnBaseButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_StrainTensor(app,'LOAD','STRAIN');
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Button pushed function: CalculateEA
        function CalculateEAButtonPushed(app, event)
            appLock(app,'LOCK');
            try exe_All(app);
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Value changed function: DplStep0Switch
        function DplStep0SwitchValueChanged(app, event)
            app.DplStep0EF.set('Visible',app.DplStep0Switch.Value);
            app.DPLValueLabel.set('Visible',app.DplStep0Switch.Value);
        end

        % Value changed function: PartialSwitch
        function PartialSwitchValueChanged(app, event)
            %PARTIALSWICH controls wheter to show or not partial options
            switch app.PartialSwitch.Value
                case 'Total';of='off';
                case 'Partial';of='on';
            end
            app.LoadSurfButton.set('Visible',of);
            app.NewRectButton.set('Visible',of);
            app.RotationLabel.set('Visible',of);
            app.RotationEF.set('Visible',of);
            if isequal(app.VoidRatioPanel.Visible,'on') ||...
                    isequal(app.StressTensorPanel.Visible,'on') ||...
                    isequal(app.AnisotropyPanel.Visible,'on') ||...
                    isequal(app.StrainTensorPanel.Visible,'on') ||...
                    isequal(app.ExeAllPanel.Visible,'on')
                app.SubdivisionButton.set('Visible',of);
                if isequal(of,'off')
                    app.SubdivisionButton.set('Value',0);
                    app.LinesEditFieldLabel.set('Visible',of);
                    app.LinesEditField.set('Visible',of);
                    app.ColEditField.set('Visible',of);
                    app.ColEditFieldLabel.set('Visible',of);
                end
            else
                app.SubdivisionButton.set('Visible','off','Value',0);
                SubdivisionButtonValueChanged(app);
            end
            %Disable/Enable calculating button
            PartButEnabler(app);
            %update Drawing
            PlotUpdater(app);
        end

        % Button pushed function: LoadSurfButton
        function LoadSurfButtonPushed(app, event)
            PD = partialData('LOAD',app.PartialData,app);
            if isempty(PD);return;end
            app.PartialData=PD;
            %Loadvalue
            app.RotationEF.Value=PD.AngleRotation;
            %Enable Button
            PartButEnabler(app);
            %Update draw image
            app.BoolRecalculateGrains=0; %ask for grain recalculation
            PlotUpdater(app, 'RECTANGLE','GRAINS'); %update draw image
        end

        % Button pushed function: NewRectButton
        function NewRectButtonPushed(app, event)
            app.NewRectangleOptionsPanel.set('Visible','On');
        end

        % Button pushed function: NRChooseinDrawingButton
        function NRChooseinDrawingButtonPushed(app, event)
            app.NewRectangleOptionsPanel.set('Visible','Off');
            PD = partialData('RECTANGLE',app.PartialData,app);
            if isempty(PD);return;end
            %Adds PD to the app properties
            app.PartialData=PD;
            %Change Rotation EF
            app.RotationEF.Value=PD.AngleRotation;
            %Enable Button
            PartButEnabler(app);
            %Update draw image
            app.BoolRecalculateGrains=0; %ask for grain recalculation
            if app.SubdivisionButton.Value==1
                app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            end
            PlotUpdater(app, 'RECTANGLE','GRAINS');
        end

        % Button pushed function: NRTriaxialButton
        function NRTriaxialButtonPushed(app, event)
            app.NewRectangleOptionsPanel.set('Visible','Off');
            PD=partialData('DATA','',app,[0,0,app.boxYEF.Value,...
                app.boxZEF.Value],app.boxXEF.Value,0);
            if isempty(PD);return;end
            %Adds PD to the app properties
            app.PartialData=PD;
            %Change Rotation EF
            app.RotationEF.Value=PD.AngleRotation;
            %Enable Button
            PartButEnabler(app);
            %Update draw image
            app.BoolRecalculateGrains=0; %ask for grain recalculation
            if app.SubdivisionButton.Value==1
                app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            end
            PlotUpdater(app, 'RECTANGLE','GRAINS');
        end

        % Button pushed function: NREnterValuesButton
        function NREnterValuesButtonPushed(app, event)
            app.NRHeightZEF.Value=app.boxZEF.Value/2;
            app.NRLengthYEF.Value=app.boxYEF.Value/2;
            app.NRWidthXEF.Value=app.boxXEF.Value;
            app.NROriginYEF.Value=app.boxZEF.Value/4;
            app.NROriginZEF.Value=app.boxYEF.Value/4;
            app.NewRectangleOptionsPanel.set('Visible','Off');
            app.NewRectangleSizePanel.set('Visible','On');
        end

        % Button pushed function: NRCreateButton
        function NRCreateButtonButtonPushed(app, event)
            PD=partialData('DATA','',app,[app.NROriginYEF.Value,...
                app.NROriginZEF.Value,app.NRLengthYEF.Value...
                app.NRHeightZEF.Value],app.NRHeightZEF.Value,...
                app.NRAngleEF.Value);
            app.NewRectangleSizePanel.set('Visible','Off');
            if isempty(PD);return;end
            %Adds PD to the app properties
            app.PartialData=PD;
            %Change Rotation EF
            app.RotationEF.Value=PD.AngleRotation;
            %Enable Button
            PartButEnabler(app);
            %Update draw image
            app.BoolRecalculateGrains=0; %ask for grain recalculation
            if app.SubdivisionButton.Value==1
                app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            end
            PlotUpdater(app, 'RECTANGLE','GRAINS');
        end

        % Button pushed function: NRCancelButton, NRCancelButton2
        function NewRectCancelButtonPushed(app, event)
            app.NewRectangleOptionsPanel.set('Visible','Off');
            app.NewRectangleSizePanel.set('Visible','Off');
        end

        % Value changed function: RotationEF
        function RotationEFValueChanged(app, event)
            value = app.RotationEF.Value;
            app.PartialData=partialData('ROTATION',app.PartialData,app,value);
            if app.SubdivisionButton.Value==1
                app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            end
            PlotUpdater(app,'RECTANGLE','GRAINS');
        end

        % Value changed function: SubdivisionButton
        function SubdivisionButtonValueChanged(app, event)
            if isempty(app.PartialData)
                app.SubdivisionButton.Value=0;return;
            end
            value = app.SubdivisionButton.Value;
            if value==1;of='On';else;of='Off';end
            app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            app.LinesEditField.Visible = of;
            app.LinesEditFieldLabel.Visible = of;
            app.ColEditFieldLabel.Visible = of;
            app.ColEditField.Visible = of;
            if ~isempty(app.PlotSubdv) %if it existis already
                PlotUpdater(app)
            else %if it has to be created
                PlotUpdater(app,'RECTANGLE'); %Update draw image
            end
        end

        % Value changed function: ColEditField, LinesEditField
        function LinesEditFieldValueChanged(app, event)
            if app.SubdivisionButton.Value==1
                app.PartialData=partialData('SUBDIVISION',app.PartialData,app);
            end
            PlotUpdater(app,'RECTANGLE','GRAINS');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            setappdata(app.CalculatingPanel,'Canceling',1);
        end

        % Button pushed function: ResetZoomButton
        function ResetZoomButtonPushed(app, event)
            app.UIAxes.set('XLimMode','auto','YLimMode','auto');
        end

        % Value changed function: FirstStepEF, LastStepEF
        function N1EFValueChanged(app, event)
            PlotUpdater(app,'BASE','GRAINS'); %Update UIAxes
        end

        % Value changed function: N1EF
        function N1EFValueChanged2(app, event)
            %Change on the Edit field must update the slider
            value = app.N1EF.Value;
            Interval=app.IntervalEF.Value;
            %Calculate closest chosen value
            NewValue=round(value/Interval)*Interval;
            app.N1EF.Value=NewValue;
            %Calculate the slider
            NewSlider=(NewValue-app.FirstStepEF.Value)/...
                (app.LastStepEF.Value-app.FirstStepEF.Value)*100;
            app.StartStepSlider.Value=NewSlider;
            app.BoolRecalculateGrains=0;
            N1EFValueChanged(app);
        end

        % Value changed function: StartStepSlider
        function StartStepSliderValueChanged(app, event)
            app.BoolRecalculateGrains=0;
            N1EFValueChanged(app);
        end

        % Value changing function: StartStepSlider
        function StartStepSliderValueChanging(app, event)
            %Change on the slider must update the Edit field
            changingValue =event.Value/100;
            Interval=app.IntervalEF.Value;
            %Calculate new value
            NewN1=changingValue*(app.LastStepEF.Value...
                -app.FirstStepEF.Value)+app.FirstStepEF.Value;
            %Calculate new value using the closest value divisible by the
            %file interval.
            NewN1=round(NewN1/Interval)*Interval;
            app.N1EF.Value=NewN1;
        end

        % Value changed function: N2EF
        function N2EFValueChanged(app, event)
            %Change on the Edit field must update the slider
            Interval=app.IntervalEF.Value;
            %Calculate closest chosen value
            NewValue=round(app.N2EF.Value/Interval)*Interval;
            app.N2EF.Value=NewValue;
            %Calculate the slider
            NewSlider=(NewValue-app.FirstStepEF.Value)/...
                (app.LastStepEF.Value-app.FirstStepEF.Value)*100;
            app.EndStepSlider.Value=NewSlider;
        end

        % Value changing function: EndStepSlider
        function EndStepSliderValueChanging(app, event)
            %Change on the slider must update the Edit field
            Interval=app.IntervalEF.Value;
            %Calculate new value
            NewN2=event.Value/100*(app.LastStepEF.Value...
                -app.FirstStepEF.Value)+app.FirstStepEF.Value;
            %Calculate new value using the closest value divisible by the
            %file interval.
            NewN2=round(NewN2/Interval)*Interval;
            app.N2EF.Value=NewN2;
        end

        % Value changed function: GrainsButton
        function GrainsButtonValueChanged(app, event)
            if isempty(app.BoolRecalculateGrains) || app.BoolRecalculateGrains==0
                PlotUpdater(app,'GRAINS');
                app.BoolRecalculateGrains=1;
            else
                PlotUpdater(app);
            end
        end

        % Value changed function: CalcInt
        function CalcIntValueChanged(app, event)
            %Calculates a value for the interval that is a multiple of the LIGGGHTS output interval
            value = app.CalcInt.Value;
            Interval=app.IntervalEF.Value;
            app.CalcInt.Value=max(round(value/Interval),1)*Interval;
        end

        % Button pushed function: TesterButton
        function TesterButtonPushed(app, event)
            test(app)
        end

        % Value changed function: SOut_EA_CB
        function SOut_EA_CBValueChanged(app, event)
            if app.SOut_EA_CB.Value; of='on';else;of='off';end
            app.SOut_EA_DropDown.Visible=of;
        end

        % Value changed function: StrT_EA_CB
        function StrT_EA_CBValueChanged(app, event)
            if app.StrT_EA_CB.Value; of='on';else;of='off';end
            app.StrT_EA_DropDown.Visible=of;
        end

        % Value changed function: FCGrVtkCBox
        function FCGrVtkCBoxValueChanged(app, event)
            if app.FCGrVtkCBox.Value; of='on';else;of='off';end
            app.FCGrVtkEF.Visible=of;
        end

        % Value changed function: FCClusterCBox
        function FCClusterCBoxValueChanged(app, event)
            if app.FCClusterCBox.Value; of='on';else;of='off';end
            app.FCGrVtkEF.Visible='off';
            app.FCGrVtkCBox.Visible=of;
            app.FCGrVtkCBox.Value=0;            
        end

        % Value changed function: FormatEF, GVpathEF, GrainsEF, 
        % ...and 4 other components
        function exportSizeEFValueChanged(app, event)
            %Any changes in all the settings that call this function
            %implies a relaunch of FirstValues
            FirstValues(app)
        end

        % Value changed function: FCBendCBox
        function FCBendCBoxValueChanged(app, event)
            app.FCBendAngleEF.Visible=app.FCBendCBox.Value;
            app.FCBuckAngleEFLabel.Visible=app.FCBendCBox.Value;
            app.FCBendCLTCBox.Visible=app.FCBendCBox.Value;
            app.FCBendCLTCBox.Value=0;
        end

        % Value changed function: StnDropDown
        function StnTDropDownValueChanged(app, event)
            s='off';
            if isequal (app.StnDropDown.Value,'PerCell') ||...
                    isequal (app.StnDropDown.Value,'Both')
                s='on';
            end
            app.StnClusterCB.Visible=s;
            app.StnVTKCB.Visible=s;
            app.StnClusterCB.Value=0;
        end

        % Button pushed function: ConsoStepButton
        function ConsoStepButtonPushed(app, event)
            app.N1EF.Value=app.ConsoStep;
            N1EFValueChanged(app)
            N1EFValueChanged2(app)
        end

        % Button pushed function: LoadEdgR
        function LoadEdgRButtonPushed(app, event)
            exe_EdgesRatio(app,'LOAD')
        end

        % Button pushed function: CalculateEdgR
        function CalculateEdgRButtonPushed(app, event)
            exe_EdgesRatio(app)
        end

        % Button pushed function: LoadSimButton
        function LoadSimButtonPushed(app, event)
            spth=app.SavePath;
            [fName,fPath,~] = uigetfile( {'*.txt;*.liggghts',...
                'Text files(*.txt,*.liggghts)';'*.*',...
                'All Files (*.*)'},'Select file for loading');
            if fName==0;return;end
            [app,stp1,stp12,stp2,intV]=logReader(app,fPath,fName);
            %Step information
            stepSlider(app,stp1,stp12,stp2,intV)
            if strcmp(app.SavePath,spth)
                warndlg(['File did not contain all the necessary data,'...
                    'or the same file was lodaded.']);
                return;
            end
            FirstValues(app);
            PlotUpdater(app,'BASE');
            %Reset sliders to initial positions
            app.EndStepSlider.Value=100;
            app.StartStepSlider.Value=0;
        end

        % Value changed function: StnLoadMenuButton
        function LoadStnMenuButtonValueChanged(app, event)
            app.CalculateStn.Enable=~app.StnLoadMenuButton.Value;
            app.StrainLoadOptionsPanel.Visible=...
                app.StnLoadMenuButton.Value;
        end

        % Button pushed function: StnLoadStnClButton
        function LoadStnClusterButtonValueChanged(app, event)
            appLock(app,'LOCK');
            try exe_StrainTensor(app,'LOAD','STNCL');
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Button pushed function: StnLoadAveClusterW2Button
        function LoadStnAveClusterW2ButtonValueChanged(app, event)
            appLock(app,'LOCK');
            try exe_StrainTensor(app,'LOAD','STNW2AC');
            catch ME
                appLock(app,'UNLOCK');
                rethrow(ME);
            end
            appLock(app,'UNLOCK');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {426, 426};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Model')
            xlabel(app.UIAxes, 'Y (m)')
            ylabel(app.UIAxes, 'Z (m)')
            app.SettingsPanel = uipanel(app.RightPanel);
            app.SettingsPanel.Title = 'Settings Panel';
            app.SettingsPanel.Visible = 'off';
            app.RadicalcotaininggrainsdataLabel = uilabel(app.SettingsPanel);
            app.RadicalcotaininggrainsdataLabel.Text = 'Radical cotaining grains data';
            app.GrainsEF = uieditfield(app.SettingsPanel, 'text');
            app.GrainsEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.GrainsEF.Value = 'grains';
            app.RadicalcotaininggrainseffortsdataLabel = uilabel(app.SettingsPanel);
            app.RadicalcotaininggrainseffortsdataLabel.Text = 'Radical cotaining grains efforts data';
            app.GrainsForceEF = uieditfield(app.SettingsPanel, 'text');
            app.GrainsForceEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.GrainsForceEF.Value = 'contForce';
            app.RadicalcotainingpistonsdataLabel = uilabel(app.SettingsPanel);
            app.RadicalcotainingpistonsdataLabel.Text = 'Radical cotaining pistons data';
            app.PistEF = uieditfield(app.SettingsPanel, 'text');
            app.PistEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.PistEF.Value = 'servoForce';
            app.FilesextensionLabel = uilabel(app.SettingsPanel);
            app.FilesextensionLabel.Text = 'Files extension';
            app.FormatEF = uieditfield(app.SettingsPanel, 'text');
            app.FormatEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.FormatEF.Value = '.txt';
            app.FilesdataLabel = uilabel(app.SettingsPanel);
            app.FilesdataLabel.Text = 'Files data :';
            app.LoadSimButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSimButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimButtonPushed, true);
            app.LoadSimButton.Text = 'Load other sim';
            app.GeometricaldatamLabel = uilabel(app.SettingsPanel);
            app.GeometricaldatamLabel.Text = 'Geometrical data (m):';
            app.HeightzLabel = uilabel(app.SettingsPanel);
            app.HeightzLabel.Text = 'Height (z)';
            app.LengthyLabel = uilabel(app.SettingsPanel);
            app.LengthyLabel.Text = ' Length (y)';
            app.WidthxLabel = uilabel(app.SettingsPanel);
            app.WidthxLabel.Text = 'Width (x)';
            app.PathtogeneralvariablesLabel = uilabel(app.SettingsPanel);
            app.PathtogeneralvariablesLabel.Text = 'Path to general variables';
            app.GVpathEF = uieditfield(app.SettingsPanel, 'text');
            app.GVpathEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.GVpathEF.Value = '../generalvariables';
            app.PathtoVTKfilesLabel = uilabel(app.SettingsPanel);
            app.PathtoVTKfilesLabel.Text = 'Path to VTK files';
            app.VTKpathEF = uieditfield(app.SettingsPanel, 'text');
            app.VTKpathEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.VTKpathEF.Value = '../post2';
            app.FirstStepLabel = uilabel(app.SettingsPanel);
            app.FirstStepLabel.Text = 'First Step';
            app.LastStepLabel = uilabel(app.SettingsPanel);
            app.LastStepLabel.Text = 'Last Step';
            app.IntervalLabel = uilabel(app.SettingsPanel);
            app.IntervalLabel.Text = 'Interval';
            app.FirstStepEF = uieditfield(app.SettingsPanel, 'numeric');
            app.FirstStepEF.Limits = [0 Inf];
            app.FirstStepEF.ValueDisplayFormat = '%.0f';
            app.FirstStepEF.ValueChangedFcn = createCallbackFcn(app, @N1EFValueChanged, true);
            app.FirstStepEF.Value = 71250;
            app.LastStepEF = uieditfield(app.SettingsPanel, 'numeric');
            app.LastStepEF.Limits = [0 Inf];
            app.LastStepEF.ValueDisplayFormat = '%.0f';
            app.LastStepEF.ValueChangedFcn = createCallbackFcn(app, @N1EFValueChanged, true);
            app.LastStepEF.Value = 1170000;
            app.IntervalEF = uieditfield(app.SettingsPanel, 'numeric');
            app.IntervalEF.Limits = [0 Inf];
            app.IntervalEF.ValueDisplayFormat = '%.0f';
            app.IntervalEF.Value = 1250;
            app.boxXEF = uieditfield(app.SettingsPanel, 'numeric');
            app.boxXEF.Limits = [0 Inf];
            app.boxXEF.Value = 0.013;
            app.boxYEF = uieditfield(app.SettingsPanel, 'numeric');
            app.boxYEF.Limits = [0 Inf];
            app.boxYEF.Value = 0.35;
            app.boxZEF = uieditfield(app.SettingsPanel, 'numeric');
            app.boxZEF.Limits = [0 Inf];
            app.boxZEF.Value = 0.42;
            app.CourbePointsSwitchLabel = uilabel(app.SettingsPanel);
            app.CourbePointsSwitchLabel.Text = 'Courbe Points';
            app.CourbePointsSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.PlotssizeLabel = uilabel(app.SettingsPanel);
            app.PlotssizeLabel.Text = 'Fig size';
            app.exportSizeEF = uieditfield(app.SettingsPanel, 'text');
            app.exportSizeEF.ValueChangedFcn = createCallbackFcn(app, @exportSizeEFValueChanged, true);
            app.exportSizeEF.Value = '[560,420]';
            app.TitlesCB = uicheckbox(app.SettingsPanel);
            app.TitlesCB.Text = 'Titles';
            app.TitlesCB.Value = true;
            app.PlotoptionsLabel = uilabel(app.SettingsPanel);
            app.PlotoptionsLabel.Text = 'Plot options :';
            app.LegendsCB = uicheckbox(app.SettingsPanel);
            app.LegendsCB.Text = 'Legends';
            app.LegendsCB.Value = true;
            app.MarkinitpeakendSwitchLabel = uilabel(app.SettingsPanel);
            app.MarkinitpeakendSwitchLabel.Text = 'Mark init,peak,end';
            app.MarkSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.MarkSwitch.Value = 'On';
            app.LinewidthLabel = uilabel(app.SettingsPanel);
            app.LinewidthLabel.Text = 'Line width';
            app.PlotWidthEF = uieditfield(app.SettingsPanel, 'numeric');
            app.PlotWidthEF.Limits = [0.5 5];
            app.PlotWidthEF.Value = 1.5;
            app.LinewidthLabel_2 = uilabel(app.SettingsPanel);
            app.LinewidthLabel_2.Text = 'Font';
            app.FontSizeEF = uieditfield(app.SettingsPanel, 'numeric');
            app.FontSizeEF.Limits = [5 20];
            app.FontSizeEF.Value = 11;
            app.StrainTensorPanel = uipanel(app.RightPanel);
            app.StrainTensorPanel.Title = 'Strain Tensor Calculation Panel';
            app.StrainTensorPanel.Visible = 'off';
            app.CalculateStn = uibutton(app.StrainTensorPanel, 'push');
            app.CalculateStn.ButtonPushedFcn = createCallbackFcn(app, @CalculateStnButtonPushed, true);
            app.CalculateStn.Text = 'Calculate';
            app.StnLabel = uilabel(app.StrainTensorPanel);
            app.StnLabel.Text = 'Calculate the strain tensor of the simulation';
            app.StnDescription = uilabel(app.StrainTensorPanel);
            app.StnDescription.Text = {'Calculate a global strain tensor or a per cell strain tensor. The global will'; 'return figures showing the evolution of strain throught the simulation. The'; 'per cell will return ''.vtk'' filest to be analyzed with Paraview.'; ''; 'The method used to do these calculations was proposed by BAGI 96 '; 'and is based in the notion of space cell systems.'};
            app.CalculationtypeDropDownLabel = uilabel(app.StrainTensorPanel);
            app.CalculationtypeDropDownLabel.Text = 'Calculation type';
            app.StnDropDown = uidropdown(app.StrainTensorPanel);
            app.StnDropDown.Items = {'Global', 'PerCell', 'Both'};
            app.StnDropDown.ValueChangedFcn = createCallbackFcn(app, @StnTDropDownValueChanged, true);
            app.StnDropDown.Value = 'Global';
            app.StnClusterCB = uicheckbox(app.StrainTensorPanel);
            app.StnClusterCB.Visible = 'off';
            app.StnClusterCB.Text = 'Strain and Cluster';
            app.StnLoadMenuButton = uibutton(app.StrainTensorPanel, 'state');
            app.StnLoadMenuButton.ValueChangedFcn = createCallbackFcn(app, @LoadStnMenuButtonValueChanged, true);
            app.StnLoadMenuButton.Text = 'Load Files';
            app.StnVTKCB = uicheckbox(app.StrainTensorPanel);
            app.StnVTKCB.Visible = 'off';
            app.StnVTKCB.Text = 'Vtk files';
            app.StnVTKCB.Value = true;
            app.LoopsPanel = uipanel(app.RightPanel);
            app.LoopsPanel.Title = 'Loop/Cluster Calculation Panel';
            app.LoopsPanel.Visible = 'off';
            app.CalculateLP = uibutton(app.LoopsPanel, 'push');
            app.CalculateLP.ButtonPushedFcn = createCallbackFcn(app, @CalculateLPButtonPushed, true);
            app.CalculateLP.Text = 'Calculate';
            app.LPLabel = uilabel(app.LoopsPanel);
            app.LPLabel.Text = 'Calculates the evolution of the number of loops/clusters ';
            app.VtkFilesLabel = uilabel(app.LoopsPanel);
            app.VtkFilesLabel.Text = 'Vtk Files';
            app.LPVtkSwitch = uiswitch(app.LoopsPanel, 'slider');
            app.LPVtkSwitch.Value = 'On';
            app.LPDescription = uilabel(app.LoopsPanel);
            app.LPDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'the struct formed throught the simulation.These can also be drawn as VTK '; 'files for better visualsation of the phenomenon. They are obtained through a '; 'post processing of the data through the Delaunay Tesselation.'; ''};
            app.ExtraOptionsButton = uibutton(app.LoopsPanel, 'state');
            app.ExtraOptionsButton.ValueChangedFcn = createCallbackFcn(app, @ExtraOptionsButtonValueChanged, true);
            app.ExtraOptionsButton.Text = 'Extra Options';
            app.LPLoadMenuButton = uibutton(app.LoopsPanel, 'state');
            app.LPLoadMenuButton.ValueChangedFcn = createCallbackFcn(app, @LoadLoopMenuButtonPushed, true);
            app.LPLoadMenuButton.Text = 'Load Files';
            app.VoidRatioPanel = uipanel(app.RightPanel);
            app.VoidRatioPanel.Title = 'Void Ratio and Porosity Calculation Panel';
            app.VoidRatioPanel.Visible = 'off';
            app.CalculateVR = uibutton(app.VoidRatioPanel, 'push');
            app.CalculateVR.ButtonPushedFcn = createCallbackFcn(app, @CalculateVRButtonPushed, true);
            app.CalculateVR.Text = 'Calculate';
            app.VRLabel = uilabel(app.VoidRatioPanel);
            app.VRLabel.Text = 'Calculates the evolution of the Void Ratio and Porosity';
            app.LoadVR = uibutton(app.VoidRatioPanel, 'push');
            app.LoadVR.ButtonPushedFcn = createCallbackFcn(app, @LoadVRButtonPushed, true);
            app.LoadVR.Text = 'Load Files';
            app.VRDescription = uilabel(app.VoidRatioPanel);
            app.VRDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'the evolution of the Void Ratio and Porosity. The calculation is done '; 'through the use of Voronoi calculation.'};
            app.StressTensorPanel = uipanel(app.RightPanel);
            app.StressTensorPanel.Title = 'Stress Tensor Calculation';
            app.StressTensorPanel.Visible = 'off';
            app.CalculateStrT = uibutton(app.StressTensorPanel, 'push');
            app.CalculateStrT.ButtonPushedFcn = createCallbackFcn(app, @CalculateStrTButtonPushed, true);
            app.CalculateStrT.Text = 'Calculate';
            app.StrTLabel = uilabel(app.StressTensorPanel);
            app.StrTLabel.Text = 'Calculates the evolution of the stress calculated on the grains';
            app.LoadStrT = uibutton(app.StressTensorPanel, 'push');
            app.LoadStrT.ButtonPushedFcn = createCallbackFcn(app, @LoadStrTButtonPushed, true);
            app.LoadStrT.Text = 'Load Files';
            app.StrTDescription = uilabel(app.StressTensorPanel);
            app.StrTDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'the intern stress using the Love-Webber formula. The per grain value of '; 'the stress tensor is obtained directly from the output file. This file is then'; 'treated to obtain the stress courbes.'; ''};
            app.ForceChainsPanel = uipanel(app.RightPanel);
            app.ForceChainsPanel.Title = 'Force Chains Calculation';
            app.ForceChainsPanel.Visible = 'off';
            app.CalculateFC = uibutton(app.ForceChainsPanel, 'push');
            app.CalculateFC.ButtonPushedFcn = createCallbackFcn(app, @CalculateFCButtonPushed, true);
            app.CalculateFC.Text = 'Calculate';
            app.FCLabel = uilabel(app.ForceChainsPanel);
            app.FCLabel.Text = 'Calculates the evolution of the stress calculated on the grains';
            app.LoadFC = uibutton(app.ForceChainsPanel, 'push');
            app.LoadFC.ButtonPushedFcn = createCallbackFcn(app, @LoadFCButtonPushed, true);
            app.LoadFC.Text = 'Load Files';
            app.FCDescription = uilabel(app.ForceChainsPanel);
            app.FCDescription.Text = {'Calculate the force chains following Peters et Al 2005.'; 'Angle limit value determinates the maximal angle between the grain''s'; 'strongest principal stress value and the contact with another highly stressed'; 'grain. Two types of calculation are possible, normal meaning only force'; 'chains and clusters wich will do a coupled analysis between the two.'};
            app.AnglelimitEditFieldLabel = uilabel(app.ForceChainsPanel);
            app.AnglelimitEditFieldLabel.Text = 'Angle limit';
            app.FCAngEF = uieditfield(app.ForceChainsPanel, 'numeric');
            app.FCAngEF.Limits = [0 90];
            app.FCAngEF.Value = 45;
            app.ForceCoefLabel = uilabel(app.ForceChainsPanel);
            app.ForceCoefLabel.Text = 'Force Coef';
            app.FCForceCoefEF = uieditfield(app.ForceChainsPanel, 'numeric');
            app.FCForceCoefEF.Limits = [0 90];
            app.FCForceCoefEF.Value = 1;
            app.FCBaseCBox = uicheckbox(app.ForceChainsPanel);
            app.FCBaseCBox.Text = 'Base';
            app.FCBaseCBox.Value = true;
            app.FCClusterCBox = uicheckbox(app.ForceChainsPanel);
            app.FCClusterCBox.ValueChangedFcn = createCallbackFcn(app, @FCClusterCBoxValueChanged, true);
            app.FCClusterCBox.Text = 'Cluster';
            app.FCGrVtkCBox = uicheckbox(app.ForceChainsPanel);
            app.FCGrVtkCBox.ValueChangedFcn = createCallbackFcn(app, @FCGrVtkCBoxValueChanged, true);
            app.FCGrVtkCBox.Visible = 'off';
            app.FCGrVtkCBox.Text = 'Grains VTK';
            app.FCGrVtkEF = uieditfield(app.ForceChainsPanel, 'text');
            app.FCGrVtkEF.Visible = 'off';
            app.FCGrVtkEF.Value = '[50,200,500]';
            app.FCBendCBox = uicheckbox(app.ForceChainsPanel);
            app.FCBendCBox.ValueChangedFcn = createCallbackFcn(app, @FCBendCBoxValueChanged, true);
            app.FCBendCBox.Text = 'Bending';
            app.FCBuckAngleEFLabel = uilabel(app.ForceChainsPanel);
            app.FCBuckAngleEFLabel.Visible = 'off';
            app.FCBuckAngleEFLabel.Text = 'Angle';
            app.FCBendAngleEF = uieditfield(app.ForceChainsPanel, 'numeric');
            app.FCBendAngleEF.Limits = [0 90];
            app.FCBendAngleEF.Visible = 'off';
            app.FCBendAngleEF.Value = 0.5;
            app.FCBendCLTCBox = uicheckbox(app.ForceChainsPanel);
            app.FCBendCLTCBox.Visible = 'off';
            app.FCBendCLTCBox.Text = 'Cluster Transformation';
            app.ExternalForcesPanel = uipanel(app.RightPanel);
            app.ExternalForcesPanel.Title = 'External Stress/Strain Calculation Panel';
            app.ExternalForcesPanel.Visible = 'off';
            app.CalculateExtF = uibutton(app.ExternalForcesPanel, 'push');
            app.CalculateExtF.ButtonPushedFcn = createCallbackFcn(app, @CalculateExtFButtonPushed, true);
            app.CalculateExtF.Text = 'Calculate';
            app.ExtFLabel = uilabel(app.ExternalForcesPanel);
            app.ExtFLabel.Text = 'Calculates the stress-strain courbes generated by the pistons';
            app.ExtFDescription = uilabel(app.ExternalForcesPanel);
            app.ExtFDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'the evolution of the stress and stress through the data obtained by the '; 'pistons.'};
            app.LoadExtF = uibutton(app.ExternalForcesPanel, 'push');
            app.LoadExtF.ButtonPushedFcn = createCallbackFcn(app, @LoadExtFButtonPushed, true);
            app.LoadExtF.Text = 'Load';
            app.ExeAllPanel = uipanel(app.RightPanel);
            app.ExeAllPanel.Title = 'Exe All Panel';
            app.ExeAllPanel.Visible = 'off';
            app.CalculateEA = uibutton(app.ExeAllPanel, 'push');
            app.CalculateEA.ButtonPushedFcn = createCallbackFcn(app, @CalculateEAButtonPushed, true);
            app.CalculateEA.Text = 'Calculate';
            app.EALabel = uilabel(app.ExeAllPanel);
            app.EALabel.Text = 'Execute All the chosen calculations';
            app.Loop_EA_CB = uicheckbox(app.ExeAllPanel);
            app.Loop_EA_CB.Text = 'Loops';
            app.VR_EA_CB = uicheckbox(app.ExeAllPanel);
            app.VR_EA_CB.Text = 'Void Ratio';
            app.VR_EA_CB.Value = true;
            app.Ani_EA_CB = uicheckbox(app.ExeAllPanel);
            app.Ani_EA_CB.Text = 'Anisotropy';
            app.Ani_EA_CB.Value = true;
            app.SInt_EA_CB = uicheckbox(app.ExeAllPanel);
            app.SInt_EA_CB.Text = 'Stress Inner';
            app.SInt_EA_CB.Value = true;
            app.StrT_EA_CB = uicheckbox(app.ExeAllPanel);
            app.StrT_EA_CB.ValueChangedFcn = createCallbackFcn(app, @StrT_EA_CBValueChanged, true);
            app.StrT_EA_CB.Text = 'Strain Tensor';
            app.StrT_EA_CB.Value = true;
            app.SOut_EA_CB = uicheckbox(app.ExeAllPanel);
            app.SOut_EA_CB.ValueChangedFcn = createCallbackFcn(app, @SOut_EA_CBValueChanged, true);
            app.SOut_EA_CB.Text = 'Stress Outer';
            app.BI_EA_CB = uicheckbox(app.ExeAllPanel);
            app.BI_EA_CB.Text = 'Basic Information';
            app.BI_EA_CB.Value = true;
            app.SOut_EA_DropDown = uidropdown(app.ExeAllPanel);
            app.SOut_EA_DropDown.Items = {'Consolidadion', 'Compression'};
            app.SOut_EA_DropDown.Value = 'Compression';
            app.StrT_EA_DropDown = uidropdown(app.ExeAllPanel);
            app.StrT_EA_DropDown.Items = {'Global', 'PerCell', 'Both'};
            app.StrT_EA_DropDown.Value = 'Global';
            app.EdgesRatioPanel = uipanel(app.RightPanel);
            app.EdgesRatioPanel.Title = 'Edges Ratio Calculation Panel';
            app.EdgesRatioPanel.Visible = 'off';
            app.CalculateEdgR = uibutton(app.EdgesRatioPanel, 'push');
            app.CalculateEdgR.ButtonPushedFcn = createCallbackFcn(app, @CalculateEdgRButtonPushed, true);
            app.CalculateEdgR.Text = 'Calculate';
            app.EdgRLabel = uilabel(app.EdgesRatioPanel);
            app.EdgRLabel.Text = 'Calculates the number of Open and Closed edges';
            app.EdgRDescription = uilabel(app.EdgesRatioPanel);
            app.EdgRDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'the evolution of the ratio between closed and open edges'};
            app.LoadEdgR = uibutton(app.EdgesRatioPanel, 'push');
            app.LoadEdgR.ButtonPushedFcn = createCallbackFcn(app, @LoadEdgRButtonPushed, true);
            app.LoadEdgR.Text = 'Load';
            app.DisplacementPanel = uipanel(app.RightPanel);
            app.DisplacementPanel.Title = 'Displacment Calculation Panel';
            app.DisplacementPanel.Visible = 'off';
            app.CalculateDpl = uibutton(app.DisplacementPanel, 'push');
            app.CalculateDpl.ButtonPushedFcn = createCallbackFcn(app, @CalculateDplButtonPushed, true);
            app.CalculateDpl.Text = 'Calculate';
            app.DplLabel = uilabel(app.DisplacementPanel);
            app.DplLabel.Text = 'Calculates the mean displacements and transform it in VTK files';
            app.DplStep0Switch = uiswitch(app.DisplacementPanel, 'slider');
            app.DplStep0Switch.ValueChangedFcn = createCallbackFcn(app, @DplStep0SwitchValueChanged, true);
            app.DplDescription = uilabel(app.DisplacementPanel);
            app.DplDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'for each chosen step the relative displacement of each grain in relation'; 'to the mean displacement of all of them. This way we can understand '; 'how  the particles are moving in relation to each other.'};
            app.DPLValueLabel = uilabel(app.DisplacementPanel);
            app.DPLValueLabel.Visible = 'off';
            app.DPLValueLabel.Text = 'Value';
            app.DplStep0EF = uieditfield(app.DisplacementPanel, 'numeric');
            app.DplStep0EF.Limits = [0 Inf];
            app.DplStep0EF.Visible = 'off';
            app.DplBasestepLabel = uilabel(app.DisplacementPanel);
            app.DplBasestepLabel.Text = 'Base step :';
            app.NeighborValuesEditFieldLabel = uilabel(app.DisplacementPanel);
            app.NeighborValuesEditFieldLabel.Text = 'Neighbor Values';
            app.NeighborValuesEditField = uieditfield(app.DisplacementPanel, 'text');
            app.NeighborValuesEditField.Value = '[50,500,1000,2000]';
            app.BasicInfoPanel = uipanel(app.RightPanel);
            app.BasicInfoPanel.Title = 'Basic Info Panel';
            app.BasicInfoPanel.Visible = 'off';
            app.CalculateBI = uibutton(app.BasicInfoPanel, 'push');
            app.CalculateBI.ButtonPushedFcn = createCallbackFcn(app, @CalculateBIButtonPushed, true);
            app.CalculateBI.Text = 'Calculate';
            app.BILabel = uilabel(app.BasicInfoPanel);
            app.BILabel.Text = 'Calculate the following information from the simulation files :';
            app.BIDescription = uilabel(app.BasicInfoPanel);
            app.BIDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate :'; ''; '- Pistons displacements in time'; '- Diameter distribution '};
            app.LoadBI = uibutton(app.BasicInfoPanel, 'push');
            app.LoadBI.Text = 'Load Files';
            app.AnisotropyPanel = uipanel(app.RightPanel);
            app.AnisotropyPanel.Title = 'Anisotropy Calculation  Panel';
            app.CalculateAni = uibutton(app.AnisotropyPanel, 'push');
            app.CalculateAni.ButtonPushedFcn = createCallbackFcn(app, @CalculateAniButtonPushed, true);
            app.CalculateAni.Text = 'Calculate';
            app.AniLabel = uilabel(app.AnisotropyPanel);
            app.AniLabel.Text = 'Calculates the evolution of the anisotropy ';
            app.AniDescription = uilabel(app.AnisotropyPanel);
            app.AniDescription.Text = {'This function will use the data obtained through LIGGGHTS to calculate'; 'a mechanical and geometrical  fabric tensor for each chosen step of '; 'the simulation. From these fabric tensors we will be able to obtain a '; 'measure of the specimen''s  anisotropy'};
            app.LoadAni = uibutton(app.AnisotropyPanel, 'push');
            app.LoadAni.ButtonPushedFcn = createCallbackFcn(app, @LoadAniButtonPushed, true);
            app.LoadAni.Text = 'Load Files';
            app.ResetZoomButton = uibutton(app.RightPanel, 'push');
            app.ResetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoomButton.Text = 'Reset Zoom';
            app.UserPanel = uipanel(app.RightPanel);
            app.UserPanel.Title = 'Enter the following Values';
            app.PartialSwitch = uiswitch(app.UserPanel, 'slider');
            app.PartialSwitch.Items = {'Total', 'Partial'};
            app.PartialSwitch.ValueChangedFcn = createCallbackFcn(app, @PartialSwitchValueChanged, true);
            app.PartialSwitch.Value = 'Total';
            app.LoadSurfButton = uibutton(app.UserPanel, 'push');
            app.LoadSurfButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSurfButtonPushed, true);
            app.LoadSurfButton.Visible = 'off';
            app.LoadSurfButton.Text = 'Load';
            app.NewRectButton = uibutton(app.UserPanel, 'push');
            app.NewRectButton.ButtonPushedFcn = createCallbackFcn(app, @NewRectButtonPushed, true);
            app.NewRectButton.Visible = 'off';
            app.NewRectButton.Text = 'New';
            app.SubdivisionButton = uibutton(app.UserPanel, 'state');
            app.SubdivisionButton.ValueChangedFcn = createCallbackFcn(app, @SubdivisionButtonValueChanged, true);
            app.SubdivisionButton.Visible = 'off';
            app.SubdivisionButton.Text = 'Subdivision';
            app.LinesEditFieldLabel = uilabel(app.UserPanel);
            app.LinesEditFieldLabel.Visible = 'off';
            app.LinesEditFieldLabel.Text = 'Lines';
            app.LinesEditField = uieditfield(app.UserPanel, 'numeric');
            app.LinesEditField.Limits = [1 20];
            app.LinesEditField.ValueChangedFcn = createCallbackFcn(app, @LinesEditFieldValueChanged, true);
            app.LinesEditField.Visible = 'off';
            app.LinesEditField.Value = 2;
            app.ColEditFieldLabel = uilabel(app.UserPanel);
            app.ColEditFieldLabel.Visible = 'off';
            app.ColEditFieldLabel.Text = 'Columns';
            app.ColEditField = uieditfield(app.UserPanel, 'numeric');
            app.ColEditField.Limits = [1 20];
            app.ColEditField.ValueChangedFcn = createCallbackFcn(app, @LinesEditFieldValueChanged, true);
            app.ColEditField.Visible = 'off';
            app.ColEditField.Value = 3;
            app.RotationLabel = uilabel(app.UserPanel);
            app.RotationLabel.Visible = 'off';
            app.RotationLabel.Text = 'Rotation (°)';
            app.RotationEF = uieditfield(app.UserPanel, 'numeric');
            app.RotationEF.Limits = [-90 90];
            app.RotationEF.ValueChangedFcn = createCallbackFcn(app, @RotationEFValueChanged, true);
            app.RotationEF.Visible = 'off';
            app.GrainsButton = uibutton(app.UserPanel, 'state');
            app.GrainsButton.ValueChangedFcn = createCallbackFcn(app, @GrainsButtonValueChanged, true);
            app.GrainsButton.Text = 'Grains';
            app.StartStepSliderLabel = uilabel(app.UserPanel);
            app.StartStepSliderLabel.Text = 'Start Step';
            app.StartStepSlider = uislider(app.UserPanel);
            app.StartStepSlider.MajorTicks = 20;
            app.StartStepSlider.MajorTickLabels = {'Consolidation'};
            app.StartStepSlider.ValueChangedFcn = createCallbackFcn(app, @StartStepSliderValueChanged, true);
            app.StartStepSlider.ValueChangingFcn = createCallbackFcn(app, @StartStepSliderValueChanging, true);
            app.StartStepSlider.MinorTicks = [];
            app.EndStepSliderLabel = uilabel(app.UserPanel);
            app.EndStepSliderLabel.Text = 'End Step';
            app.EndStepSlider = uislider(app.UserPanel);
            app.EndStepSlider.MajorTicks = [];
            app.EndStepSlider.MajorTickLabels = {''};
            app.EndStepSlider.ValueChangingFcn = createCallbackFcn(app, @EndStepSliderValueChanging, true);
            app.EndStepSlider.MinorTicks = [];
            app.EndStepSlider.Value = 100;
            app.N1EF = uieditfield(app.UserPanel, 'numeric');
            app.N1EF.ValueDisplayFormat = '%.0f';
            app.N1EF.ValueChangedFcn = createCallbackFcn(app, @N1EFValueChanged2, true);
            app.N2EF = uieditfield(app.UserPanel, 'numeric');
            app.N2EF.ValueDisplayFormat = '%.0f';
            app.N2EF.ValueChangedFcn = createCallbackFcn(app, @N2EFValueChanged, true);
            app.CalculationIntervalEditFieldLabel = uilabel(app.UserPanel);
            app.CalculationIntervalEditFieldLabel.Text = 'Calculation Interval';
            app.CalcInt = uieditfield(app.UserPanel, 'numeric');
            app.CalcInt.ValueDisplayFormat = '%.0f';
            app.CalcInt.ValueChangedFcn = createCallbackFcn(app, @CalcIntValueChanged, true);
            app.CalcInt.Value = 1250;
            app.ConsoStepButton = uibutton(app.UserPanel, 'push');
            app.ConsoStepButton.ButtonPushedFcn = createCallbackFcn(app, @ConsoStepButtonPushed, true);
            app.ConsoStepButton.Text = {'Conso'; 'Step'};
            app.CalculatingPanel = uipanel(app.RightPanel);
            app.CalculatingPanel.Title = 'Calculating';
            app.CalculatingPanel.Visible = 'off';
            app.StepTracker = uilabel(app.CalculatingPanel);
            app.StepTracker.Text = 'Step - 0000';
            app.CancelButton = uibutton(app.CalculatingPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TimeRem = uilabel(app.CalculatingPanel);
            app.TimeRem.Text = 'Estimating remaining time ....';
            app.WarningPanel = uipanel(app.RightPanel);
            app.WarningPanel.Title = 'Warning';
            app.CouldntfindthenecessaryfilesforDrawingLabel = uilabel(app.WarningPanel);
            app.CouldntfindthenecessaryfilesforDrawingLabel.Text = {'Couldnt find the necessary files for Drawing!'; 'Try changing first file''s number or settings values'};
            app.NewRectangleOptionsPanel = uipanel(app.RightPanel);
            app.NewRectangleOptionsPanel.Title = 'New Rectangle Options';
            app.NewRectangleOptionsPanel.Visible = 'off';
            app.NRChooseinDrawingButton = uibutton(app.NewRectangleOptionsPanel, 'push');
            app.NRChooseinDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @NRChooseinDrawingButtonPushed, true);
            app.NRChooseinDrawingButton.Text = {'Choose by'; 'Drawing'};
            app.NREnterValuesButton = uibutton(app.NewRectangleOptionsPanel, 'push');
            app.NREnterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @NREnterValuesButtonPushed, true);
            app.NREnterValuesButton.Text = 'Enter Values';
            app.NRTriaxialButton = uibutton(app.NewRectangleOptionsPanel, 'push');
            app.NRTriaxialButton.ButtonPushedFcn = createCallbackFcn(app, @NRTriaxialButtonPushed, true);
            app.NRTriaxialButton.Enable = 'off';
            app.NRTriaxialButton.Visible = 'off';
            app.NRTriaxialButton.Text = 'Triaxial';
            app.NRCancelButton2 = uibutton(app.NewRectangleOptionsPanel, 'push');
            app.NRCancelButton2.ButtonPushedFcn = createCallbackFcn(app, @NewRectCancelButtonPushed, true);
            app.NRCancelButton2.Text = {'Cancel'; ''};
            app.NewRectangleSizePanel = uipanel(app.RightPanel);
            app.NewRectangleSizePanel.Title = 'New Rectangle Size';
            app.NewRectangleSizePanel.Visible = 'off';
            app.NRCreateButton = uibutton(app.NewRectangleSizePanel, 'push');
            app.NRCreateButton.ButtonPushedFcn = createCallbackFcn(app, @NRCreateButtonButtonPushed, true);
            app.NRCreateButton.Text = 'Create';
            app.NRCancelButton = uibutton(app.NewRectangleSizePanel, 'push');
            app.NRCancelButton.ButtonPushedFcn = createCallbackFcn(app, @NewRectCancelButtonPushed, true);
            app.NRCancelButton.Text = 'Cancel';
            app.OriginyEditFieldLabel = uilabel(app.NewRectangleSizePanel);
            app.OriginyEditFieldLabel.Text = 'Origin (y)';
            app.NROriginYEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.OriginzEditFieldLabel = uilabel(app.NewRectangleSizePanel);
            app.OriginzEditFieldLabel.Text = 'Origin (z)';
            app.NROriginZEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.WidgthxEditFieldLabel = uilabel(app.NewRectangleSizePanel);
            app.WidgthxEditFieldLabel.Text = 'Widgth (x)';
            app.NRWidthXEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.LengthyLabel_2 = uilabel(app.NewRectangleSizePanel);
            app.LengthyLabel_2.Text = ' Length (y)';
            app.NRLengthYEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.HeightzEditFieldLabel = uilabel(app.NewRectangleSizePanel);
            app.HeightzEditFieldLabel.Text = 'Height (z)';
            app.NRHeightZEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.AngleLabel = uilabel(app.NewRectangleSizePanel);
            app.AngleLabel.Text = 'Angle';
            app.NRAngleEF = uieditfield(app.NewRectangleSizePanel, 'numeric');
            app.TestingPanel = uipanel(app.RightPanel);
            app.TestingPanel.Title = 'Testing';
            app.TestingPanel.Visible = 'off';
            app.TesterButton = uibutton(app.TestingPanel, 'push');
            app.TesterButton.ButtonPushedFcn = createCallbackFcn(app, @TesterButtonPushed, true);
            app.TesterButton.Text = 'Tester Button';
            app.LoopsLoadOptionsPanel = uipanel(app.RightPanel);
            app.LoopsLoadOptionsPanel.Title = 'Loops Load';
            app.LoopsLoadOptionsPanel.Visible = 'off';
            app.LoadLoopBLButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopBLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopBLButtonPushed, true);
            app.LoadLoopBLButton.Text = 'Basic Load';
            app.LoadLoopVRButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopVRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopVRButtonPushed, true);
            app.LoadLoopVRButton.Text = 'Void Ratio';
            app.LoadLoopDefButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopDefButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopDefButtonPushed, true);
            app.LoadLoopDefButton.Text = 'Deformability';
            app.LoadLoopAniButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopAniButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopAniButtonPushed, true);
            app.LoadLoopAniButton.Text = 'Anisotropy';
            app.LoadLoopStrButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopStrButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopStrButtonPushed, true);
            app.LoadLoopStrButton.Text = 'Stress';
            app.LoadLoopTrfButton = uibutton(app.LoopsLoadOptionsPanel, 'push');
            app.LoadLoopTrfButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLoopTrfButtonPushed, true);
            app.LoadLoopTrfButton.Text = 'Clst Transf';
            app.ExtraClusterOptionsPanel = uipanel(app.RightPanel);
            app.ExtraClusterOptionsPanel.Title = 'Extra Cluster Options';
            app.ExtraClusterOptionsPanel.Visible = 'off';
            app.StressLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.StressLabel.Enable = 'off';
            app.StressLabel.Text = 'Stress';
            app.LPStrSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPStrSwitch.Enable = 'off';
            app.LPVRSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPVRSwitchLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPVRSwitchLabel.Text = 'Void Ratio';
            app.LPAniSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPAniSwitchLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPAniSwitchLabel.Text = 'Anisotropy';
            app.LPDefSwitchLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPDefSwitchLabel.Text = 'Deformability';
            app.LPDefSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPCTrSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPCTrSwitchLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPCTrSwitchLabel.Text = 'Transformation';
            app.LPStnSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPStnSwitch.Enable = 'off';
            app.LPStnSwitchLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPStnSwitchLabel.Text = 'Strain';
            app.LPACSwitch = uiswitch(app.ExtraClusterOptionsPanel, 'slider');
            app.LPACLabel = uilabel(app.ExtraClusterOptionsPanel);
            app.LPACLabel.Text = 'Grain Ave Cat';
            app.StrainLoadOptionsPanel = uipanel(app.RightPanel);
            app.StrainLoadOptionsPanel.Title = 'Strain Load Panel';
            app.StrainLoadOptionsPanel.Visible = 'off';
            app.StnLoadBaseButton = uibutton(app.StrainLoadOptionsPanel, 'push');
            app.StnLoadBaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStnBaseButtonPushed, true);
            app.StnLoadBaseButton.Text = 'Load Strain';
            app.StnLoadStnClButton = uibutton(app.StrainLoadOptionsPanel, 'push');
            app.StnLoadStnClButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStnClusterButtonValueChanged, true);
            app.StnLoadStnClButton.Text = 'Cluster - W2';
            app.StnLoadAveClusterW2Button = uibutton(app.StrainLoadOptionsPanel, 'push');
            app.StnLoadAveClusterW2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadStnAveClusterW2ButtonValueChanged, true);
            app.StnLoadAveClusterW2Button.Text = 'Ave Cluster - W2';
            app.LIGGGHTSAnalysisButtonGroup = uibuttongroup(app.RightPanel);
            app.LIGGGHTSAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LIGGGHTSAnalysisButtonGroupSelectionChanged, true);
            app.VoidRatioButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.VoidRatioButton.Text = 'Void Ratio';
            app.StressTensorButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.StressTensorButton.Text = 'Stress Tensor';
            app.StrainTensorButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.StrainTensorButton.Text = 'Strain Tensor';
            app.SettingsButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.SettingsButton.Text = 'Settings';
            app.LoopsClustersButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.LoopsClustersButton.Text = 'Loops/Clusters';
            app.ForceChainButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.ForceChainButton.Text = 'Force Chain';
            app.ExtStressStrainButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.ExtStressStrainButton.Text = 'Ext Stress/Strain';
            app.ExeAllButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.ExeAllButton.Enable = 'off';
            app.ExeAllButton.Text = 'Execute All';
            app.EdgesRatioButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.EdgesRatioButton.Visible = 'off';
            app.EdgesRatioButton.Text = 'Edges Ratio';
            app.DisplacementButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.DisplacementButton.Visible = 'off';
            app.DisplacementButton.Text = 'Displacement';
            app.BasicInfoButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.BasicInfoButton.Text = 'Basic Info';
            app.AnisotropyButton = uitogglebutton(app.LIGGGHTSAnalysisButtonGroup);
            app.AnisotropyButton.Text = 'Anisotropy';
            app.AnisotropyButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalyseExe(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end