classdef SubPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CenterPlotButton   matlab.ui.control.Button
        SavePlotButton     matlab.ui.control.Button
        PlotButton         matlab.ui.control.Button
        TypeDropDown       matlab.ui.control.DropDown
        TypeDropDownLabel  matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        ListBoxLabel       matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = private)
        %Values
        CallingApp  %App that called this one, LIGGGHTSdata
        ConsoPoint  %Step in witch we turn from consolidation to compression
        Mode        %String containing the info on which button called this function so we can behave diferently
        SubValues   %Values for subdivision
        PrevSelect  %Contain the values of previous selections of the listbox
        D           %dimension
        %PlotRelated
        Lgd         %Legend values
        Labels      %Data ARRAY containing all labels
        plotHandles %Graphic group containing the handles to set the courbes visibility on or off
        plotType    %Value containing if we are plotting VR or PR
        PlotData    %Data to be plotted
        %Boolean
        BoolAllvisible %if 1 all plots are visible, if 0 no
    end
    
    methods (Access = private)
        function MainPlotMatrix(app)
            %MAINPLOTMATRIX plot files when PlotData is a matrix
            %   This function is called when the PlotData is a matrix. It
            %   will create all the plots that will later be used by other
            %   functions.
            
            %clear all plots on the axis
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            
            %Get important data
            selected=app.Labels ;           %all plot types
            nbPlot=max(size(selected));     %number of plots
            plotHand = gobjects(nbPlot,1);  %handles array
            sigma=char(963);
            eps=char(949);
            %Change plot colors with 'linspecer' function
            C=linspecer(nbPlot);
            app.UIAxes.set('ColorOrder',C);
            app.BoolAllvisible=0; %turn off bolean, it will be turned on in necessary functions
            PD=app.PlotData;
            switch upper(app.Mode)
                case 'ANI'
                    if app.ConsoPoint>PD(1,end,1) && app.ConsoPoint<PD(end,end,1)
                        tf=1;
                    else
                        tf=0;
                    end
                    grpLabel=app.Labels;
                    switch app.TypeDropDown.Value
                        case "Coordination Number"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,2,i));
                            end
                            app.UIAxes.Title.String ="Evolution of coordination number";
                            app.UIAxes.YLabel.String="Coordination number";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                        case "Geometric Anisotropy"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,3,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Geometric anisotropy";
                            app.UIAxes.YLabel.String="Geometric Anisotropy";
                            app.UIAxes.XLabel.String="Vertical Strain";
                        case "Mechanical Anisotropy"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,4,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Mechanical anisotropy";
                            app.UIAxes.YLabel.String="Mechanical Anisotropy";
                            app.UIAxes.XLabel.String="Vertical Strain";
                        case "Total Contacts"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,5,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Total Contacts";
                            app.UIAxes.YLabel.String="Total Contacts";
                            app.UIAxes.XLabel.String="Vertical Strain";
                        case "Horizontal Contacts"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,6,i)./PD(:,5,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Horizontal Contacts";
                            app.UIAxes.YLabel.String="Horizontal ratio";
                            app.UIAxes.XLabel.String="Vertical Strain";
                        case "Vertical contacts"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),...
                                    PD(:,7,i)./PD(:,5,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Vertical contacts";
                            app.UIAxes.YLabel.String="Contacts ratio";
                            app.UIAxes.XLabel.String="Vertical Strain";
                    end
                    app.Lgd=legend(app.UIAxes,grpLabel,'location','eastoutside');
                    app.plotHandles=plotHand;
                case 'INTSTR'
                    nSub=app.SubValues(1)*app.SubValues(2);
                    app.BoolAllvisible =0;
                    grpLabel=app.Labels;
                    app.ListBox.set('Enable',1);
                    if app.ConsoPoint>PD(1,2,1) && app.ConsoPoint<PD(end,2,1)
                        tf=1;
                    else
                        tf=0;
                    end
                    switch app.TypeDropDown.Value
                        case "q=f(p)"
                            plotHand(1)=plot(app.UIAxes,PD(:,4,1),PD(:,3,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,4,end),PD(:,3,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,4,i),PD(:,3,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the stress";
                            app.UIAxes.YLabel.String="Deviatoric stress q (kPa)";
                            app.UIAxes.XLabel.String= "Principal stress p (kPa)";
                            tf=0;
                        case "p=f(Ez)"
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,4,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,end),PD(:,4,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,2,i),PD(:,4,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the Principal stress";
                            app.UIAxes.YLabel.String="Principal stress p (kPa)";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                        case "q=f(Ez)"
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,3,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,end),PD(:,3,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,2,i),PD(:,3,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the Deviatoric stress";
                            app.UIAxes.YLabel.String="Deviatoric stress q (kPa)";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                        case "Mean value"
                            app.ListBox.set('Enable',0);
                            plotHand=plotHand(1:4);
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,3,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,1),PD(:,4,1),'o');
                            plotHand(3)=plot(app.UIAxes,PD(:,2,1),...
                                (sum(PD(:,3,2:(end-1)),3)/nSub),':x');
                            plotHand(4)=plot(app.UIAxes,PD(:,2),...
                                (sum(PD(:,4,2:(end-1)),3)/nSub),':o');
                            grpLabel=["Partial Q";"Partial P";...
                                "Mean Sub Q";"Mean Sub P"];
                            app.UIAxes.Title.String ="Compare mean value with all calculation";
                            app.UIAxes.YLabel.String="Stress (kPa)";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                            app.BoolAllvisible =1;
                        case [sigma 'X=f(Ez)']
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,5,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,end),PD(:,5,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,2,i),PD(:,5,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the stress on X direction";
                            app.UIAxes.YLabel.String=[sigma 'X (kPa)'];
                            app.UIAxes.XLabel.String= "Vertical Strain";
                            app.Lgd=legend(app.UIAxes,grpLabel,'location','eastoutside');
                            app.plotHandles=plotHand;
                        case [sigma 'Y=f(Ez)']
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,6,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,end),PD(:,6,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,2,i),PD(:,6,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the stress on Y direction";
                            app.UIAxes.YLabel.String=[sigma 'Y (kPa)'];
                            app.UIAxes.XLabel.String="Vertical Strain";
                        case [sigma 'Z=f(Ez)']
                            plotHand(1)=plot(app.UIAxes,PD(:,2,1),PD(:,7,1),'x');
                            plotHand(2)=plot(app.UIAxes,PD(:,2,end),PD(:,7,end),'o');
                            for i=2:(nbPlot-1)
                                plotHand(i+1)=plot(app.UIAxes,PD(:,2,i),PD(:,7,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the stress on Z direction";
                            app.UIAxes.YLabel.String=[sigma 'Z (kPa)'];
                            app.UIAxes.XLabel.String= "Vertical Strain";

                    end
                    app.Lgd=legend(app.UIAxes,grpLabel,'location','eastoutside');
                    app.plotHandles=plotHand;
                    
                case 'LOOPS'
                    if app.ConsoPoint>PD(1,end) && app.ConsoPoint<PD(end,end)
                        tf=1;
                    else
                        tf=0;
                    end
                    if isequal(app.TypeDropDown.Value,"Mean Value")
                        plot(app.UIAxes,PD(:,2),PD(:,end-1),'-x');
                        plot(app.UIAxes,PD(:,2),PD(:,end));
                        app.Lgd=legend(app.UIAxes,'Mean calculation','Coordination calculation',...
                            'location','eastoutside');
                        app.plotHandles='';
                        app.ListBox.set('Enable',0);
                        app.UIAxes.Title.String ="Evolution of mean Loop Size";
                        app.UIAxes.YLabel.String="Mean Loop Size";
                        app.UIAxes.XLabel.String= "Vertical Strain";
                        app.plotHandles='';
                    else
                        app.ListBox.set('Enable',1);
                        for i=1:nbPlot
                            plotHand(i)=plot(app.UIAxes,PD(:,2),PD(:,i+2));
                        end
                        grpLabel=app.Labels;
                        app.UIAxes.Title.String ="Evolution of number of Loops";
                        app.UIAxes.YLabel.String="Number of Loops";
                        app.UIAxes.XLabel.String= "Vertical Strain";
                        app.Lgd=legend(app.UIAxes,grpLabel,'location','eastoutside');
                        app.plotHandles=plotHand;
                    end
                    
                case 'STRAIN'
                    if app.ConsoPoint>PD(1,end,1) && app.ConsoPoint<PD(end,end,1)
                        tf=1;
                    else
                        tf=0;
                    end
                    switch app.TypeDropDown.Value
                        case [eps 'X=f(t)']
                            for i=1:(nbPlot)
                                plotHand(i)=plot(app.UIAxes,PD(:,1,i),PD(:,2,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the strain on X direction";
                            app.UIAxes.YLabel.String=[eps 'X'];
                            app.UIAxes.XLabel.String= "Time (s)";
                            app.plotHandles=plotHand;
                        case [eps 'Y=f(t)']
                            for i=1:(nbPlot)
                                plotHand(i)=plot(app.UIAxes,PD(:,1,i),PD(:,app.D,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the strain on Y direction";
                            app.UIAxes.YLabel.String=[eps 'Y'];
                            app.UIAxes.XLabel.String= "Time (s)";
                            app.plotHandles=plotHand;
                        case [eps 'Z=f(t)']
                            for i=1:(nbPlot)
                                plotHand(i)=plot(app.UIAxes,PD(:,1,i),PD(:,app.D+1,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the strain on Z direction";
                            app.UIAxes.YLabel.String=[eps 'Z'];
                            app.UIAxes.XLabel.String= "Time (s)";
                            app.plotHandles=plotHand;
                        case ['Deviatoric' eps ]
                            for i=1:(nbPlot)
                                plotHand(i)=plot(app.UIAxes,PD(:,1,i),PD(:,app.D+1,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the deviatoric strain";
                            app.UIAxes.YLabel.String="Deviatoric Strain";
                            app.UIAxes.XLabel.String= "Time (s)";
                            app.plotHandles=plotHand;
                        case ['Principal' eps ]
                            for i=1:(nbPlot)
                                plotHand(i)=plot(app.UIAxes,PD(:,1,i),PD(:,app.D+1,i));
                            end
                            app.UIAxes.Title.String ="Evolution of the Principal strain";
                            app.UIAxes.YLabel.String="Principal Strain";
                            app.UIAxes.XLabel.String= "Time (s)";
                            app.plotHandles=plotHand;
                    end
                    app.Lgd=legend(app.UIAxes,app.Labels,'location','eastoutside');
                    app.plotHandles=plotHand;
                    
                case 'VOID'
                    if app.ConsoPoint>PD(1,end,1) && app.ConsoPoint<PD(end,end,1)
                        tf=1;
                    else
                        tf=0;
                    end
                    switch app.TypeDropDown.Value
                        case "Void Ratio"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),PD(:,2,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Void Ratio";
                            app.UIAxes.YLabel.String="Void raito";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                        case 'Porosity'
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,end,i),PD(:,3,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Porosity";
                            app.UIAxes.YLabel.String="Porosity";
                            app.UIAxes.XLabel.String= "Vertical Strain";
                        case "e=log(p)"
                            for i=1:nbPlot
                                plotHand(i)=plot(app.UIAxes,PD(:,4,i),PD(:,2,i));
                            end
                            app.UIAxes.Title.String ="Evolution of Void Ratio";
                            app.UIAxes.YLabel.String="Void raito";
                            app.UIAxes.XLabel.String= "Log(p)";
                            tf=0;
                    end
                    app.Lgd=legend(app.UIAxes,app.Labels,'location','eastoutside');
                    app.plotHandles=plotHand;
            end
            %Line separating Consolidation and compression
            if tf
                csLine=xline(app.UIAxes,app.ConsoPoint,'Color','k');
                set(get(get(csLine,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode, plotData, labels, consoPoint, varargin)
            %Set entries as properties
            app.CallingApp=mainapp;
            app.Mode=mode;
            app.PlotData=plotData;
            app.Labels=labels;
            app.ConsoPoint=consoPoint;
            if nargin>=7
                app.SubValues=varargin{1};
            end
            
            %Start fucntions
            app.ListBox.Items=labels; %listbox itens are the same as the labels
            app.ListBox.Value=labels; %all valus are selected on startup
            app.PrevSelect=labels;    %value for 'ListBoxValueChanged'
            app.TypeDropDown.set('Visible','on');
            
            %Start up function specific values
            switch upper(app.Mode)
                case 'ANI'
                    app.TypeDropDown.Items=["Coordination Number";...
                        "Geometric Anisotropy";"Mechanical Anisotropy";...
                        "Total Contacts";"Horizontal Contacts";"Vertical contacts"];
                case 'INTSTR'
                    sigma=char(963);
                    app.TypeDropDown.Items=["q=f(p)";"p=f(Ez)";"q=f(Ez)";[sigma 'X=f(Ez)'];...
                        [sigma 'Y=f(Ez)'];[sigma 'Z=f(Ez)'];"Mean value"];
                case 'LOOPS'
                    app.TypeDropDown.Items=["All Loops";"Mean Value"];
                case 'STRAIN'
                    app.D=size(plotData,2)-3;
                    eps=string(char(949));
                    if app.D==3
                        app.TypeDropDown.Items=[eps+"X=f(t)";eps+"Y=f(t)";...
                            eps+"Z=f(t)";"Deviatoric"+eps;"Principal"+eps];
                    else
                        app.TypeDropDown.Items=[eps+"Y=f(t)";eps+"Z=f(t)";...
                            "Deviatoric"+eps;"Principal"+eps];
                    end
                case 'VOID'
                    app.TypeDropDown.Items=["Void Ratio";"Porosity";"e=log(p)"];
            end
            MainPlotMatrix(app);
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            selected=app.ListBox.Value;
            [~,~,ib]=intersect(selected,app.Labels); %check indice of selected labels
            %if user changed the plot type we have to update it by
            %calling 'Firstplot' again
            MainPlotMatrix(app);
            %for one plot this handle will be off
            if isempty(app.plotHandles); return; end
            %Set visible on/off
            for i=1:size(app.plotHandles,1)
                if ~isempty(intersect(i,ib)) || app.BoolAllvisible %if it was chosen turn on visibility
                    app.plotHandles(i).set('Visible','on');
                else %if it was not chosen turn off visibility
                    app.plotHandles(i).set('Visible','off');
                end
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %We want to be able to select and unselect values.
            value = app.ListBox.Value;
            prevVal=app.PrevSelect;
            for i=1:size(value,1)
                val=value(i,:);
                if isempty(intersect(val,prevVal)) %select
                    prevVal=[prevVal,val]; %#ok<AGROW>
                else    %unselect
                    prevVal=prevVal(prevVal~=val);
                end
            end
            app.ListBox.Value=prevVal;
            app.PrevSelect=prevVal;
        end

        % Button pushed function: CenterPlotButton
        function CenterPlotButtonPushed(app, event)
            app.UIAxes.set('XLimMode','auto','YLimMode','auto');
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            %get data
            pd=app.PlotData;
            if iscell(pd)
                filename=['multi' app.TypeDropDown.Value '.jpg'];
            else
                N1=pd(1,1);N2=pd(end,1);
                filename=[app.Mode N1 'to' N2 app.TypeDropDown.Value '.jpg'];
            end
            %Find path and desired filename
            [file,path] = uiputfile({';*.jpg;*.png;*.fig;*.tiff;'}...
                ,'Save Plot',filename);
            if file==0;return;end
            %Copy graphic objects into a new figure
            f = figure('Visible','off');
            axNew = axes;
            % Copy all objects from UIAxes to new axis
            copyobj(app.UIAxes.Children,axNew);
            % Save all parameters of the UIAxes
            uiAxParams = get(app.UIAxes);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axNew));
            % Remove the UIAxes params that aren't editable in the
            % new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children';...
                'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition';...
                'InnerPosition'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            set(axNew, uiAxGoodParams);
            %Legend
            lgndName1 = app.UIAxes.Legend.String;
            sz=size(lgndName1,2);
            if sz>1  %legend, only worth it if more than one courbe
                str=string(1:sz);
                for i=1:sz %copy all legend na;es
                    str(i)=cell2mat(lgndName1(1,i));
                end
                lgd = legend(axNew,str);
                lgd.Box =app.UIAxes.Legend.Box;
                lgd.Location = app.UIAxes.Legend.Location;
            end
            %Save file
            OldF=cd;
            cd(path)
            saveas(f,file);
            cd(OldF)
            delete(f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Plot1', 'Plot2'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Plot1', 'Plot2'};
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {};
            app.TypeDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.CenterPlotButton = uibutton(app.UIFigure, 'push');
            app.CenterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CenterPlotButtonPushed, true);
            app.CenterPlotButton.Text = 'Center Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubPlotter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end