classdef ClusterAniPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        ButtonMoveL           matlab.ui.control.Button
        ButtonMoveR           matlab.ui.control.Button
        Slider                matlab.ui.control.Slider
        EvQAxes               matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        ValueDropDown2        matlab.ui.control.DropDown
        ValueDropDown_2Label  matlab.ui.control.Label
        ValueDropDown1        matlab.ui.control.DropDown
        ValueDropDownLabel    matlab.ui.control.Label
        TypeDropDown1         matlab.ui.control.DropDown
        TypeDropDown2         matlab.ui.control.DropDown
        TypeDropDown_2Label   matlab.ui.control.Label
        OrderDropDown2        matlab.ui.control.DropDown
        OrderDropDown_2Label  matlab.ui.control.Label
        TypeDropDownLabel     matlab.ui.control.Label
        OrderDropDown1        matlab.ui.control.DropDown
        OrderDropDownLabel    matlab.ui.control.Label
        PanelRos1             matlab.ui.container.Panel
        PanelRos2             matlab.ui.container.Panel
        CrapTesterButton      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Res     % Results
        Pax     %polar axes handles
        Step    %slider calculation
        Rline   %redline handle
    end
    
    methods (Access = private)
        function createRosette(app,pNb)
            for i=1:numel(pNb)
                cla(app.Pax(pNb(i)));
                %Check if plot1 or 2
                if pNb(i)==1
                    typ=str2double(app.TypeDropDown1.Value); %surf vs grvt
                    val=str2double(app.ValueDropDown1.Value);%dev vs eig
                    ord=str2double(app.OrderDropDown1.Value);%ord
                else
                    typ=str2double(app.TypeDropDown2.Value);
                    val=str2double(app.ValueDropDown2.Value);
                    ord=str2double(app.OrderDropDown2.Value);
                end
                %get the results of the step
                res=app.Res.Anisotropy{app.Step};
                ang=abs(res.Angles(:,typ,1))*pi()/180; %get only the angle values
                rad=res.Values(:,typ,val);
                %get the wanted order
                switch ord
                    case 0;ids=1:numel(res.Order);
                    case 6;ids=find(res.Order==6);
                    case 8;ids=find(res.Order>6 & res.Order<22);
                    case 22;ids=find(res.Order>20);
                end
                polarscatter(app.Pax(pNb(i)),ang(ids),rad(ids))
                if val==1 && typ==1     %dev+surf
                    app.Pax(pNb(i)).RLim=[0,1.5];
                elseif val==1 && typ==2 %dev+grvt
                    app.Pax(pNb(i)).RLim=[0,1];
                elseif val==2 && typ==1 %eig+surf
                    app.Pax(pNb(i)).RLim=[0,0.4];
                else                    %eig+grvt
                    app.Pax(pNb(i)).RLim=[0,0.8];
                end
                app.Pax(pNb(i)).ThetaLim = [0 90];
            end
        end
        function getStep(app)
            app.Step=find(app.Slider.MinorTicks==app.Slider.Value);
        end
        function redLine(app)
            if ~isempty(app.Rline);delete(app.Rline);end
            app.Rline=xline(app.EvQAxes,app.Res.Strain(app.Step),':r');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pD, AniTypes)
            %save property
            app.Res=pD.Results;
            %Create polar axes
            app.Pax=gobjects(2,1);
            app.Pax(1) = polaraxes(app.PanelRos1);
            app.Pax(2) = polaraxes(app.PanelRos2);
            app.TypeDropDown1.Items=cellstr(AniTypes);
            app.TypeDropDown2.Items=cellstr(AniTypes);
            %First do the left graph Ev and Q = f(Ez) in two dif axes
            plot(app.EvQAxes,app.Res.Strain,app.Res.QEv(:,1), '-+k')
            ylabel(app.EvQAxes,'Deviatoric stress [kPa]')
            yyaxis(app.EvQAxes,'right')
            plot(app.EvQAxes,app.Res.Strain,app.Res.QEv(:,2),'-+',...
                'Color','#a1a1a1')
            app.EvQAxes.YDir='reverse';
            ylabel(app.EvQAxes,'Volumetric strain')
            app.EvQAxes.YAxis(1).Color='k';
            app.EvQAxes.YAxis(2).Color='#a1a1a1';
            title(app.EvQAxes,['Evolution of q and ' char(949) 'v'])
            xlabel(app.EvQAxes,'Axial Strain')
            
            %Slider - fix position and make values match the x axis
            app.Slider.Position(3)=app.EvQAxes.InnerPosition(3);
            app.Slider.Position(1)=app.EvQAxes.InnerPosition(1);
            app.Slider.MajorTicks=[];
            app.Slider.MinorTicks=(app.Res.Strain-app.Res.Strain(1))/...
                (app.Res.Strain(end)-app.Res.Strain(1))*100;
            %get first step
            getStep(app);
            %make first plot
            redLine(app)
            createRosette(app,[1,2])
        end

        % Button pushed function: CrapTesterButton
        function CrapTesterButtonPushed(app, event)
            test(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            val = app.Slider.Value;
            [~,pos]=min(abs(val-app.Slider.MinorTicks));
            app.Slider.Value=app.Slider.MinorTicks(pos);
            app.Step=pos;
            redLine(app)
            createRosette(app,[1,2])
        end

        % Value changed function: OrderDropDown1, TypeDropDown1, 
        % ValueDropDown1
        function OrderDropDown1ValueChanged(app, event)
            createRosette(app,1)
        end

        % Value changed function: OrderDropDown2, TypeDropDown2, 
        % ValueDropDown2
        function OrderDropDown2ValueChanged(app, event)
            createRosette(app,2)
        end

        % Button pushed function: ButtonMoveL
        function ButtonMoveLPushed(app, event)
            if app.Step>1
                app.Step=app.Step-1;
                app.Slider.Value=app.Slider.MinorTicks(app.Step);
                redLine(app);createRosette(app,[1,2])
            end
        end

        % Button pushed function: ButtonMoveR
        function ButtonMoveRPushed(app, event)
            if app.Step<numel(app.Slider.MinorTicks)
                app.Step=app.Step+1;
                app.Slider.Value=app.Slider.MinorTicks(app.Step);
                redLine(app);createRosette(app,[1,2])
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {448, 448};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {448, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EvQAxes = uiaxes(app.LeftPanel);
            title(app.EvQAxes, 'Title')
            xlabel(app.EvQAxes, 'X')
            ylabel(app.EvQAxes, 'Y')
            zlabel(app.EvQAxes, 'Z')
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ButtonMoveR = uibutton(app.LeftPanel, 'push');
            app.ButtonMoveR.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveRPushed, true);
            app.ButtonMoveR.Text = '>';
            app.ButtonMoveL = uibutton(app.LeftPanel, 'push');
            app.ButtonMoveL.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveLPushed, true);
            app.ButtonMoveL.Text = '<';
            app.RightPanel = uipanel(app.GridLayout);
            app.PanelRos2 = uipanel(app.RightPanel);
            app.PanelRos1 = uipanel(app.RightPanel);
            app.OrderDropDownLabel = uilabel(app.RightPanel);
            app.OrderDropDownLabel.Text = 'Order';
            app.OrderDropDown1 = uidropdown(app.RightPanel);
            app.OrderDropDown1.Items = {'All', '6', '8-20', '22+', ''};
            app.OrderDropDown1.ItemsData = {'0', '6', '8', '22', ''};
            app.OrderDropDown1.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown1ValueChanged, true);
            app.OrderDropDown1.Value = '6';
            app.TypeDropDownLabel = uilabel(app.RightPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.OrderDropDown_2Label = uilabel(app.RightPanel);
            app.OrderDropDown_2Label.Text = 'Order';
            app.OrderDropDown2 = uidropdown(app.RightPanel);
            app.OrderDropDown2.Items = {'All', '6', '8-20', '22+', ''};
            app.OrderDropDown2.ItemsData = {'0', '6', '8', '22', ''};
            app.OrderDropDown2.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown2ValueChanged, true);
            app.OrderDropDown2.Value = '6';
            app.TypeDropDown_2Label = uilabel(app.RightPanel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown2 = uidropdown(app.RightPanel);
            app.TypeDropDown2.Items = {'Surface', 'Gravity'};
            app.TypeDropDown2.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.TypeDropDown2.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown2ValueChanged, true);
            app.TypeDropDown2.Value = '2';
            app.TypeDropDown1 = uidropdown(app.RightPanel);
            app.TypeDropDown1.Items = {'Surface', 'Gravity'};
            app.TypeDropDown1.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.TypeDropDown1.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown1ValueChanged, true);
            app.TypeDropDown1.Value = '1';
            app.ValueDropDownLabel = uilabel(app.RightPanel);
            app.ValueDropDownLabel.Text = 'Value';
            app.ValueDropDown1 = uidropdown(app.RightPanel);
            app.ValueDropDown1.Items = {'Deviatoric', 'Eignen'};
            app.ValueDropDown1.ItemsData = {'1', '2'};
            app.ValueDropDown1.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown1ValueChanged, true);
            app.ValueDropDown1.Value = '1';
            app.ValueDropDown_2Label = uilabel(app.RightPanel);
            app.ValueDropDown_2Label.Text = 'Value';
            app.ValueDropDown2 = uidropdown(app.RightPanel);
            app.ValueDropDown2.Items = {'Deviatoric', 'Eignen'};
            app.ValueDropDown2.ItemsData = {'1', '2'};
            app.ValueDropDown2.ValueChangedFcn = createCallbackFcn(app, @OrderDropDown2ValueChanged, true);
            app.ValueDropDown2.Value = '1';
            app.CrapTesterButton = uibutton(app.UIFigure, 'push');
            app.CrapTesterButton.ButtonPushedFcn = createCallbackFcn(app, @CrapTesterButtonPushed, true);
            app.CrapTesterButton.Text = 'CrapTester';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusterAniPlotter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end